classdef InterfazCompleta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MENPRINCIPALTab                 matlab.ui.container.Tab
        Image2                          matlab.ui.control.Image
        BUSCARButton                    matlab.ui.control.Button
        ALTADEEQUIPOButton              matlab.ui.control.Button
        ORDENDESERVICIOButton           matlab.ui.control.Button
        DETALLESDESERVICIOButton        matlab.ui.control.Button
        REPORTEDEINCIDENTESButton       matlab.ui.control.Button
        CALENDARIZACIONButton           matlab.ui.control.Button
        CALENDARIZARMANTENIMIENTOButton  matlab.ui.control.Button
        REGISTRODEINGENIEROSButton      matlab.ui.control.Button
        Image4                          matlab.ui.control.Image
        Image4_2                        matlab.ui.control.Image
        Image4_3                        matlab.ui.control.Image
        Image4_4                        matlab.ui.control.Image
        Image4_5                        matlab.ui.control.Image
        Image4_6                        matlab.ui.control.Image
        Image4_7                        matlab.ui.control.Image
        Image4_8                        matlab.ui.control.Image
        BUSCARTab                       matlab.ui.container.Tab
        Buscarpor                       matlab.ui.control.Label
        Buscarpormenu                   matlab.ui.control.DropDown
        Buscarporedit                   matlab.ui.control.EditField
        PanelTabla                      matlab.ui.container.Panel
        Inventario                      matlab.ui.control.Table
        Buscarpormenu_2                 matlab.ui.control.DropDown
        Buscarporedit_2                 matlab.ui.control.EditField
        OpcionesFiltro                  matlab.ui.container.Panel
        Label_12                        matlab.ui.control.Label
        AreaCheckBox                    matlab.ui.control.CheckBox
        ProveedordeCompraCheckBox       matlab.ui.control.CheckBox
        AccesoriosCheckBox              matlab.ui.control.CheckBox
        NumActivoFijoCheckBox           matlab.ui.control.CheckBox
        UbicacionCheckBox               matlab.ui.control.CheckBox
        FechadeInstalacinCheckBox       matlab.ui.control.CheckBox
        EstadodelEquipoCheckBox         matlab.ui.control.CheckBox
        ProveedorDeServicioCheckBox     matlab.ui.control.CheckBox
        ContactoCheckBox                matlab.ui.control.CheckBox
        TelefonoCheckBox                matlab.ui.control.CheckBox
        RefaccionesCambiadasCheckBox    matlab.ui.control.CheckBox
        Button_3                        matlab.ui.control.Button
        DETALLEDELEQUIPOTab             matlab.ui.container.Tab
        Panel_6                         matlab.ui.container.Panel
        InformacindelequipoPanel_2      matlab.ui.container.Panel
        ActualizarInformacindelEquipoButton  matlab.ui.control.Button
        InformacindelequipoPanel_3      matlab.ui.container.Panel
        text9_33                        matlab.ui.control.Label
        text9_34                        matlab.ui.control.Label
        text9_35                        matlab.ui.control.Label
        text9_36                        matlab.ui.control.Label
        text9_37                        matlab.ui.control.Label
        text9_38                        matlab.ui.control.Label
        text9_39                        matlab.ui.control.Label
        text9_40                        matlab.ui.control.Label
        text9_41                        matlab.ui.control.Label
        FechaSolicitud_24               matlab.ui.control.EditField
        FechaSolicitud_25               matlab.ui.control.EditField
        FechaSolicitud_26               matlab.ui.control.EditField
        FechaSolicitud_27               matlab.ui.control.EditField
        FechaSolicitud_28               matlab.ui.control.EditField
        FechaSolicitud_29               matlab.ui.control.EditField
        FechaSolicitud_30               matlab.ui.control.EditField
        FechaSolicitud_31               matlab.ui.control.EditField
        FechaSolicitud_32               matlab.ui.control.EditField
        UBICACINDropDownLabel_2         matlab.ui.control.Label
        UBICACIONDropDown_2             matlab.ui.control.DropDown
        AREADropDown_2Label             matlab.ui.control.Label
        AREADropDown_2                  matlab.ui.control.DropDown
        PROVEEDORDESERVICIODropDown_2Label  matlab.ui.control.Label
        PROVEEDORDESERVICIODropDown_2   matlab.ui.control.DropDown
        REFACCIONESCAMBIADASEditField_2Label  matlab.ui.control.Label
        REFACCIONESCAMBIADASEditField_2  matlab.ui.control.EditField
        text9_42                        matlab.ui.control.Label
        Consumibles_2                   matlab.ui.control.EditField
        BorrarEquipoButton              matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        OrdendeservicioTab              matlab.ui.container.Tab
        tablaOrdenesServicio            matlab.ui.control.Table
        EditarOrdendeServicioButton     matlab.ui.control.Button
        ReportedeincidentesTab          matlab.ui.container.Tab
        tablaIncidentes                 matlab.ui.control.Table
        EditarReportedeIncidentesButton  matlab.ui.control.Button
        MantenimientosTab               matlab.ui.container.Tab
        tablaMantenimiento              matlab.ui.control.Table
        EditarMantenimientoButton       matlab.ui.control.Button
        ActualizarinformacindelmantenimientoPanel  matlab.ui.container.Panel
        FechaDatePickerLabel_2          matlab.ui.control.Label
        ActualizarMantenimientoFecha    matlab.ui.control.DatePicker
        ActualizarEstadoServicio        matlab.ui.container.ButtonGroup
        ProgramadoButton_2              matlab.ui.control.RadioButton
        RealizadoButton_2               matlab.ui.control.RadioButton
        ActualizarTipoServicio          matlab.ui.container.ButtonGroup
        MantenimientoCorrectivoButton_2  matlab.ui.control.RadioButton
        MantenimientoPreventivoButton_2  matlab.ui.control.RadioButton
        ACTUALIZARButton                matlab.ui.control.Button
        ALTADEEQUIPOTab                 matlab.ui.container.Tab
        AltadeequipoPanel               matlab.ui.container.Panel
        InformacindelequipoPanel_4      matlab.ui.container.Panel
        text9_43                        matlab.ui.control.Label
        text9_44                        matlab.ui.control.Label
        text9_45                        matlab.ui.control.Label
        text9_46                        matlab.ui.control.Label
        text9_47                        matlab.ui.control.Label
        text9_48                        matlab.ui.control.Label
        text9_49                        matlab.ui.control.Label
        text9_50                        matlab.ui.control.Label
        text9_51                        matlab.ui.control.Label
        FechaSolicitud_15               matlab.ui.control.EditField
        FechaSolicitud_16               matlab.ui.control.EditField
        FechaSolicitud_17               matlab.ui.control.EditField
        FechaSolicitud_18               matlab.ui.control.EditField
        FechaSolicitud_19               matlab.ui.control.EditField
        FechaSolicitud_20               matlab.ui.control.EditField
        FechaSolicitud_21               matlab.ui.control.EditField
        FechaSolicitud_22               matlab.ui.control.EditField
        FechaSolicitud_23               matlab.ui.control.EditField
        UBICACINDropDownLabel_3         matlab.ui.control.Label
        UBICACIONDropDown               matlab.ui.control.DropDown
        AREADropDown_3Label             matlab.ui.control.Label
        AREADropDown                    matlab.ui.control.DropDown
        PROVEEDORDESERVICIODropDown_3Label  matlab.ui.control.Label
        PROVEEDORDESERVICIODropDown     matlab.ui.control.DropDown
        Button_4                        matlab.ui.control.Button
        REFACCIONESCAMBIADASEditField_3Label  matlab.ui.control.Label
        REFACCIONESCAMBIADASEditField   matlab.ui.control.EditField
        text9_52                        matlab.ui.control.Label
        Consumibles                     matlab.ui.control.EditField
        GUARDAREQUIPOButton             matlab.ui.control.Button
        DatosdelproveedordeservicioPanel  matlab.ui.container.Panel
        CONTACTOEditFieldLabel          matlab.ui.control.Label
        CONTACTOEditField               matlab.ui.control.EditField
        ProvNombre                      matlab.ui.control.EditField
        TELFONOEditFieldLabel           matlab.ui.control.Label
        TELFONOEditField                matlab.ui.control.NumericEditField
        NOMBRELabel                     matlab.ui.control.Label
        GUARDARPROVEEDORDESERVICIOButton  matlab.ui.control.Button
        ORDENDESERVICIOTab              matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        PanelOrden                      matlab.ui.container.Panel
        uipanel1                        matlab.ui.container.Panel
        text5                           matlab.ui.control.Label
        text6                           matlab.ui.control.Label
        text7                           matlab.ui.control.Label
        ordenNumControl                 matlab.ui.control.Label
        ordenNumSerie                   matlab.ui.control.Label
        OrdenEquipoNombre               matlab.ui.control.Label
        uipanel3                        matlab.ui.container.Panel
        text9                           matlab.ui.control.Label
        OrdenFolio                      matlab.ui.control.Label
        text21                          matlab.ui.control.Label
        text22                          matlab.ui.control.Label
        FechaSolicitud                  matlab.ui.control.DatePicker
        FechaTerminacion                matlab.ui.control.DatePicker
        uipanel2                        matlab.ui.container.Panel
        text10                          matlab.ui.control.Label
        Depto                           matlab.ui.control.EditField
        Falla                           matlab.ui.control.EditField
        QuienReporta                    matlab.ui.control.EditField
        text15                          matlab.ui.control.Label
        text19                          matlab.ui.control.Label
        text23                          matlab.ui.control.Label
        uipanel4                        matlab.ui.container.Panel
        TrabajoHecho                    matlab.ui.control.EditField
        text12                          matlab.ui.control.Label
        text24                          matlab.ui.control.Label
        QuienRecibe                     matlab.ui.control.EditField
        text25                          matlab.ui.control.Label
        ASIGNADOADropDownLabel          matlab.ui.control.Label
        ASIGNADOADropDown               matlab.ui.control.DropDown
        uipanel7                        matlab.ui.container.Panel
        Refacciones                     matlab.ui.control.EditField
        tect30                          matlab.ui.control.Label
        uipanel10                       matlab.ui.container.Panel
        Observaciones                   matlab.ui.control.EditField
        text31                          matlab.ui.control.Label
        DatosGrales                     matlab.ui.control.Label
        uipanel2_2                      matlab.ui.container.Panel
        text10_2                        matlab.ui.control.Label
        text15_3                        matlab.ui.control.Label
        text15_4                        matlab.ui.control.Label
        TotalHoras                      matlab.ui.control.EditField
        CostoRefacciones                matlab.ui.control.EditField
        CostoServExterno                matlab.ui.control.EditField
        text15_5                        matlab.ui.control.Label
        Total                           matlab.ui.control.EditField
        HORASINGENIEROEditFieldLabel    matlab.ui.control.Label
        HORASINGENIEROEditField         matlab.ui.control.NumericEditField
        uipanel2_3                      matlab.ui.container.Panel
        text10_3                        matlab.ui.control.Label
        RecibidoPor                     matlab.ui.control.EditField
        Comentarios                     matlab.ui.control.EditField
        text15_6                        matlab.ui.control.Label
        text19_2                        matlab.ui.control.Label
        text23_2                        matlab.ui.control.Label
        fechaOrdenServicio              matlab.ui.control.DatePicker
        IB                              matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        FALLAENCONTRADAButtonGroup      matlab.ui.container.ButtonGroup
        EquipoDefectuosoButton          matlab.ui.control.RadioButton
        MalaOperacionButton             matlab.ui.control.RadioButton
        AsesoraTecnicaButton            matlab.ui.control.RadioButton
        TRABAJOREALIZADOButtonGroup     matlab.ui.container.ButtonGroup
        ManttoPreventivoButton          matlab.ui.control.RadioButton
        ManttoCorrectivoButton          matlab.ui.control.RadioButton
        AsesoraenServicioButton         matlab.ui.control.RadioButton
        ServicioExternoButton           matlab.ui.control.RadioButton
        GuardarOrdenBoton               matlab.ui.control.Button
        ImprimirOrdenBoton              matlab.ui.control.Button
        REPORTEDEINCIDENTESTab          matlab.ui.container.Tab
        PanelOrden_3                    matlab.ui.container.Panel
        uipanel1_3                      matlab.ui.container.Panel
        DatosGrales_3                   matlab.ui.control.Label
        uipanel4_3                      matlab.ui.container.Panel
        text12_6                        matlab.ui.control.Label
        text26_3                        matlab.ui.control.Label
        Asignado_6                      matlab.ui.control.EditField
        text12_7                        matlab.ui.control.Label
        text12_8                        matlab.ui.control.Label
        text12_9                        matlab.ui.control.Label
        text15_18                       matlab.ui.control.Label
        text15_19                       matlab.ui.control.Label
        text15_20                       matlab.ui.control.Label
        text15_21                       matlab.ui.control.Label
        Asignado_8                      matlab.ui.control.EditField
        Asignado_9                      matlab.ui.control.EditField
        Asignado_10                     matlab.ui.control.EditField
        Asignado_11                     matlab.ui.control.EditField
        Asignado_12                     matlab.ui.control.EditField
        Asignado_13                     matlab.ui.control.EditField
        Asignado_14                     matlab.ui.control.EditField
        Asignado_15                     matlab.ui.control.EditField
        Asignado_16                     matlab.ui.control.EditField
        Asignado_17                     matlab.ui.control.EditField
        Asignado_18                     matlab.ui.control.EditField
        Asignado_19                     matlab.ui.control.EditField
        FechaIncidenteLabel             matlab.ui.control.Label
        IncidenteFecha                  matlab.ui.control.DatePicker
        uipanel5_5                      matlab.ui.container.Panel
        SeguridadEquipoCheckBox_2       matlab.ui.control.CheckBox
        MalaOperacinCheckBox_3          matlab.ui.control.CheckBox
        DispositivosMdicoseHigieneCheckBox  matlab.ui.control.CheckBox
        uipanel2_7                      matlab.ui.container.Panel
        text10_7                        matlab.ui.control.Label
        CostoServExterno_3              matlab.ui.control.EditField
        IB_3                            matlab.ui.control.Label
        Image_3                         matlab.ui.control.Image
        uipanel5_6                      matlab.ui.container.Panel
        ProteccinyVigilanciaCheckBox_2  matlab.ui.control.CheckBox
        EmergenciasCheckBox_2           matlab.ui.control.CheckBox
        MaterialesPeligrososCheckBox_2  matlab.ui.control.CheckBox
        SistemasclaveCheckBox_2         matlab.ui.control.CheckBox
        uipanel5_7                      matlab.ui.container.Panel
        ProcesosAdministrativosCheckBox_2  matlab.ui.control.CheckBox
        PacienteCheckBox_2              matlab.ui.control.CheckBox
        MdicoCheckBox_2                 matlab.ui.control.CheckBox
        OtrosCheckBox_2                 matlab.ui.control.CheckBox
        QuienReporta_4                  matlab.ui.control.EditField
        uipanel2_8                      matlab.ui.container.Panel
        text10_8                        matlab.ui.control.Label
        CostoServExterno_4              matlab.ui.control.EditField
        text12_3                        matlab.ui.control.Label
        Asignado_3                      matlab.ui.control.EditField
        text12_4                        matlab.ui.control.Label
        text12_5                        matlab.ui.control.Label
        Asignado_5                      matlab.ui.control.EditField
        uipanel2_9                      matlab.ui.container.Panel
        text10_9                        matlab.ui.control.Label
        CostoServExterno_5              matlab.ui.control.EditField
        text10_10                       matlab.ui.control.Label
        uipanel2_10                     matlab.ui.container.Panel
        text10_11                       matlab.ui.control.Label
        CostoServExterno_6              matlab.ui.control.EditField
        Label                           matlab.ui.control.Label
        TIPODEINCIDENTEButtonGroup      matlab.ui.container.ButtonGroup
        CasifallaButton                 matlab.ui.control.RadioButton
        EventoAdversoButton             matlab.ui.control.RadioButton
        Button3                         matlab.ui.control.RadioButton
        text5_2                         matlab.ui.control.Label
        text6_2                         matlab.ui.control.Label
        text7_2                         matlab.ui.control.Label
        ordenNumControl_2               matlab.ui.control.Label
        ordenNumSerie_2                 matlab.ui.control.Label
        OrdenEquipoNombre_2             matlab.ui.control.Label
        FechadelReporrteLabel           matlab.ui.control.Label
        FechaFin                        matlab.ui.control.DatePicker
        GuardarIncidenteButton          matlab.ui.control.Button
        CALENDARIZARMANTENIMIENTOTab    matlab.ui.container.Tab
        Panel_4                         matlab.ui.container.Panel
        ESPECIFICACIONESPanel           matlab.ui.container.Panel
        text9_14                        matlab.ui.control.Label
        text9_15                        matlab.ui.control.Label
        text9_16                        matlab.ui.control.Label
        text9_17                        matlab.ui.control.Label
        text9_18                        matlab.ui.control.Label
        text9_19                        matlab.ui.control.Label
        text9_20                        matlab.ui.control.Label
        text9_21                        matlab.ui.control.Label
        text9_22                        matlab.ui.control.Label
        servicioEquipoNombre            matlab.ui.control.Label
        servicioMarca                   matlab.ui.control.Label
        servicioModelo                  matlab.ui.control.Label
        servicioNumSerie                matlab.ui.control.Label
        servicioArea                    matlab.ui.control.Label
        servicioProveedorCompra         matlab.ui.control.Label
        servicioNumActivoFlujo          matlab.ui.control.Label
        servicioUbicacion               matlab.ui.control.Label
        servicioProveedorServicio       matlab.ui.control.Label
        TipodeServicioButtonGroup       matlab.ui.container.ButtonGroup
        MantenimientoCorrectivoButton   matlab.ui.control.RadioButton
        MantenimientoPreventivoButton   matlab.ui.control.RadioButton
        FechaDatePickerLabel            matlab.ui.control.Label
        MantenimientoFecha              matlab.ui.control.DatePicker
        AGENDARServicioButton           matlab.ui.control.Button
        Panel_5                         matlab.ui.container.Panel
        IB_4                            matlab.ui.control.Label
        Image_4                         matlab.ui.control.Image
        EstadodelServicioButtonGroup    matlab.ui.container.ButtonGroup
        ProgramadoButton                matlab.ui.control.RadioButton
        RealizadoButton                 matlab.ui.control.RadioButton
        CALENDARIOTab                   matlab.ui.container.Tab
        TablaCalendario                 matlab.ui.control.Table
        Buscarpor_2                     matlab.ui.control.Label
        CalendarioMes                   matlab.ui.control.DropDown
        Buscarpor_3                     matlab.ui.control.Label
        CalendarioYear                  matlab.ui.control.DropDown
        FiltrarButton                   matlab.ui.control.Button
        RemoverFiltroButton             matlab.ui.control.Button
        INGENIEROSTab                   matlab.ui.container.Tab
        imagenIngenirero                matlab.ui.control.Image
        ALTAEINFORMACINDEINGENIEROSLabel  matlab.ui.control.Label
        RegistrodeIngenieroNuevoPanel   matlab.ui.container.Panel
        NombreCompletoEditFieldLabel    matlab.ui.control.Label
        IngenieroNombre                 matlab.ui.control.EditField
        ExperienciaLabel                matlab.ui.control.Label
        IngenieroExp                    matlab.ui.control.TextArea
        CapacitacionesLabel             matlab.ui.control.Label
        IngenieroCapacitaciones         matlab.ui.control.TextArea
        GUARDARINGENIEROButton          matlab.ui.control.Button
        IngenieroTabla                  matlab.ui.control.Table
        BuscarEditFieldLabel            matlab.ui.control.Label
        IngenieroBuscar                 matlab.ui.control.EditField
    end

    
    properties (Access = public)
        Ypanel=-44;
        tipoFiltro = "equipo"
        tablaFiltro = "Equipo"
        queryFiltrado = ""
        conn = []
        areas = []
        ubicaciones = []
        proveedoresServicio = []
        inventarioCompleto = [] 
        EquipoSeleccionado = []
        detalleQuery = []
        TablaMantenimiento = []
        TablaOrdenServicio = []
        TablaIncidentes = []
        ingenieros = []
        mantenimientoSeleccionado = []
        ordenServicioSeleccionada = []
        incidenteSeleccionada = []
        areaVisible = 'Area.nombre,';
        proveedorCompraVisible = 'Equipo.proveedor,';
        accesoriosVisible = 'Equipo.accesorios,';
        numActivoFijoVisible = 'Equipo.numActivoFijo,';
        ubicacionVisible = 'Ubicacion.nombre,';
        fechaInstalVisible = 'Equipo.fechaInstal,';
        estadoVisible = 'Equipo.estado,';
        consumiblesVisible = 'Equipo.consumibles,'
        proveedorServicioVisible = 'ProveedorServicio.nombre,';
        refaccionesVisible = 'Equipo.refaccionesCambiadas,'
        contactoVisible = 'ProveedorServicio.contacto,';
        telefonoVisible = 'ProveedorServicio.telefono';
        mainQuery = '';
        FileNames = {}; % Current list of files
        DirNames = {}; % Current list of directories corresponding to each file
        FileLabels = {}; % File labels
        SelectedCells; % Rows selected in the table of file names

    end
    
    methods (Access = private)
        function slider1_CreateFcn(~, hObject, ~, handles)
            % hObject    handle to slider1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
        
    end
    
    methods (Access = public)


        function results = Splash(app)
            app.Image9.Visible=false;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Interfaz_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to Interfaz (see VARARGIN)
%             % Choose default command line output for Interfaz
%             app.output = hObject;
%             % Update handles structure
%             guidata(hObject, handles);

              app.PanelOrden.Scrollable = true;
            app.mainQuery = sprintf('SELECT Equipo.numControl,Equipo.equipo,Equipo.marca,Equipo.modelo,Equipo.numeroSerie,%s %s %s %s %s %s %s %s %s %s %s %s FROM Equipo JOIN Area ON Equipo.area = Area.id JOIN Ubicacion ON Equipo.ubicacion=Ubicacion.id JOIN ProveedorServicio ON Equipo.proveedorServicio = ProveedorServicio.id;', app.numActivoFijoVisible, app.areaVisible,app.ubicacionVisible,app.proveedorCompraVisible,app.fechaInstalVisible,app.estadoVisible,app.consumiblesVisible,app.accesoriosVisible ,app.refaccionesVisible,app.proveedorServicioVisible,app.contactoVisible,app.telefonoVisible);
            dbfile = fullfile(pwd,'Inventario.db');
            connection = sqlite(dbfile);
            inventario = fetch(connection,app.mainQuery);
           % disp(inventario)
            %close(conn);
            app.conn = connection;
            app.tipoFiltro = 'equipo';
            
            app.Inventario.Data = inventario;
            app.IngenieroTabla.Data = fetch(app.conn,'Select Ingeniero.nombre, Ingeniero.experiencia, Ingeniero.capacitacion FROM Ingeniero ORDER BY Ingeniero.nombre');
            app.inventarioCompleto = inventario;
%             app.Image9.Visible=false;
            app.areas = fetch(app.conn,'SELECT Area.nombre FROM Area');
            app.ubicaciones = fetch(app.conn,'SELECT Ubicacion.nombre FROM Ubicacion');
            app.proveedoresServicio = fetch(app.conn,'SELECT ProveedorServicio.nombre FROM ProveedorServicio ORDER BY id');
            app.ProvNombre.Visible='off'; 
            app.AREADropDown.Items=app.areas;
            app.AREADropDown_2.Items=app.areas;
            app.UBICACIONDropDown.Items=app.ubicaciones;
            app.UBICACIONDropDown_2.Items=app.ubicaciones;
            app.PROVEEDORDESERVICIODropDown.Items = app.proveedoresServicio;
            app.PROVEEDORDESERVICIODropDown_2.Items = app.proveedoresServicio;
            app.ingenieros = fetch(app.conn,'SELECT Ingeniero.nombre from Ingeniero');
            disp("INGENIEROS")
            disp(app.ingenieros)
            app.ASIGNADOADropDown.Items = app.ingenieros;
            equiposYMantenimientos = fetch(app.conn,'Select Equipo.equipo,Equipo.modelo,Servicio.tipo,Servicio.estado,Servicio.fecha from Equipo JOIN Servicio ON Equipo.numControl = Servicio.Equipo');
            disp(equiposYMantenimientos)
            app.TablaCalendario.Data = equiposYMantenimientos
            %{'Número de control', 'Equipo', 'Marca', 'Modelo', 'No. De serie', 'Área', 'Proveedor de compra', 'Accesorios', 'No. De activo fijo', 'Ubicación', 'Fecha de Instalación', 'Estado de equipo', 'Provedor de servicio', 'Refacciones cambiadas', 'Consumibles'};
            %close(conn);
        end

        % Callback function
        function BotonmasButtonPushed(app, event)
            app.OpcionesFiltro.Position=[18,649+app.Ypanel,1494,30];
            app.PanelTabla.Position=[18,18+app.Ypanel,1509,654];
            app.Buscarpormenu_2.Visible=true;
            app.Buscarporedit_2.Visible=true;
        end

        % Callback function
        function BotonBorrarButtonPushed(app, event)
            app.PanelTabla.Position=[18,18,1509,654];  
            app.OpcionesFiltro.Position=[18,649,1494,30];
            app.Buscarpormenu_2.Visible=false;
            app.Buscarporedit_2.Visible=false;
        end

        % Callback function
        function BuscarporeditValueChanged(app, event)
            value = app.Buscarporedit.Value;
            disp(value)

        end

        % Value changed function: Buscarporedit
        function filtrar(app, event)
            value = app.Buscarporedit.Value;
            tabla = app.tablaFiltro;
            if strcmp(value,'TODA')
                buscar = fetch(app.conn,app.mainQuery);
            elseif strcmp(app.tablaFiltro,'numControl')
                buscar = sprintf('SELECT Equipo.numControl,Equipo.equipo,Equipo.marca,Equipo.modelo,Equipo.numeroSerie,Equipo.numActivoFijo, Area.nombre,Ubicacion.nombre,Equipo.proveedor,Equipo.fechaInstal,Equipo.estado,Equipo.consumibles,Equipo.accesorios ,Equipo.refaccionesCambiadas,ProveedorServicio.nombre,ProveedorServicio.contacto,ProveedorServicio.telefono FROM Equipo JOIN Area ON Equipo.area = Area.id JOIN Ubicacion ON Equipo.ubicacion=Ubicacion.id JOIN ProveedorServicio ON Equipo.proveedorServicio = ProveedorServicio.id WHERE %s.%s = "%s";',tabla,app.tipoFiltro,value);
            else
                buscar = sprintf('SELECT Equipo.numControl,Equipo.equipo,Equipo.marca,Equipo.modelo,Equipo.numeroSerie,Equipo.numActivoFijo,Area.nombre,Ubicacion.nombre,Equipo.proveedor,Equipo.fechaInstal,Equipo.estado,Equipo.consumibles,Equipo.accesorios,Equipo.refaccionesCambiadas,ProveedorServicio.nombre,ProveedorServicio.contacto,ProveedorServicio.telefono FROM Equipo JOIN Area ON Equipo.area = Area.id JOIN Ubicacion ON Equipo.ubicacion=Ubicacion.id JOIN ProveedorServicio ON Equipo.proveedorServicio = ProveedorServicio.id WHERE %s.%s LIKE "%s%%";',tabla,app.tipoFiltro,value);
            end
            disp(buscar)
            filtro = fetch(app.conn,buscar);
            app.Inventario.Data = filtro;
        end

        % Value changed function: Buscarpormenu
        function actualizarTipoFiltro(app, event)
            value = app.Buscarpormenu.Value;
            if strcmp(value,'Número de control')
               app.tipoFiltro = 'numControl';
               app.tablaFiltro = 'Equipo';
            elseif strcmp(value,'Equipo')
                app.tipoFiltro = 'equipo';
                app.tablaFiltro = 'Equipo';
            elseif strcmp(value,'Marca')
                app.tipoFiltro = 'marca';
                app.tablaFiltro = 'Equipo';
            elseif strcmp(value,'Modelo')
                app.tipoFiltro = 'modelo';
                app.tablaFiltro = 'Equipo';
            elseif strcmp(value,'No. De serie')
                app.tipoFiltro = 'numeroSerie';
                app.tablaFiltro = 'Equipo';
            elseif strcmp(value,'Área')
                app.tipoFiltro = 'nombre';
                app.tablaFiltro = 'Area';
            elseif strcmp(value,'Proveedor de compra')
                app.tipoFiltro = 'proveedor';
                app.tablaFiltro = 'Equipo';
            elseif strcmp(value,'Accesorios')
                app.tipoFiltro = 'accesorios';
                app.tablaFiltro = 'Equipo';
            elseif strcmp(value,'Ubicación')
                app.tipoFiltro = 'nombre';
                app.tablaFiltro = 'Ubicacion';
            elseif strcmp(value,'Fecha de Instalación')
                app.tipoFiltro = 'fechaInstal';
                app.tablaFiltro = 'Equipo';
            elseif strcmp(value,'Estado de equipo')
                app.tipoFiltro = 'estado';
                app.tablaFiltro = 'Equipo';
            elseif strcmp(value,'Proveedor de servicio')
                app.tipoFiltro = 'nombre';
                app.tablaFiltro = 'ProveedorServicio';
            elseif strcmp(value,'Refacciones cambiadas')
                app.tipoFiltro = 'refaccionesCambiadas';
                app.tablaFiltro = 'Equipo';
            elseif strcmp(value,'Consumibles')
                app.tipoFiltro = 'consumibles';
            else
                app.tablaFiltro = 'TODA';
            end
        end

        % Button pushed function: BUSCARButton
        function BUSCARButtonPushed(app, event)
           app.TabGroup.SelectedTab=app.BUSCARTab;
           
        end

        % Button pushed function: ALTADEEQUIPOButton
        function ALTADEEQUIPOButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.ALTADEEQUIPOTab;
            
        end

        % Button pushed function: ORDENDESERVICIOButton
        function ORDENDESERVICIOButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.ORDENDESERVICIOTab;
            if size(app.EquipoSeleccionado) == 0
                    aicon=imread('warning.jpg');
                    mh=msgbox('No haz seleccionado un Equipo','Error','custom',aicon);
                    th = findall(mh, 'Type', 'Text');                   %get handle to text within msgbox
                    th.FontSize = 14;      
                    deltaWidth = sum(th.Extent([1,3]))-mh.Position(3) + th.Extent(1);
                    deltaHeight = sum(th.Extent([2,4]))-mh.Position(4) + 10;
                    mh.Position([3,4]) = mh.Position([3,4]) + [deltaWidth, deltaHeight];
                    app.TabGroup.SelectedTab = app.BUSCARTab;
                end
        end

        % Callback function
        function InventarioCellSelection(app, event)
            app.SelectedCells = event.Indices;
        end

        % Button pushed function: GUARDAREQUIPOButton
        function GuardarEquipo(app, event)
EQUIPO= app.FechaSolicitud_15.Value;
            MARCA=app.FechaSolicitud_16.Value;
            MODELO= app.FechaSolicitud_17.Value;
            NUMSERIE = app.FechaSolicitud_18.Value;
            ESTADO = app.FechaSolicitud_22.Value;
            PROVCOM=app.FechaSolicitud_19.Value;
            ACCESORIOS=app.FechaSolicitud_20.Value;
            NUMACTFIJO=app.FechaSolicitud_23.Value;
            UBICACION=app.UBICACIONDropDown.Value;
            FINST=app.FechaSolicitud_21.Value;
            PROVSER=app.PROVEEDORDESERVICIODropDown.Value;
            CONTACTO=app.CONTACTOEditField.Value;
            AREA=app.AREADropDown.Value;
            REFAC = app.REFACCIONESCAMBIADASEditField.Value;
            PROVEEDOR = app.PROVEEDORDESERVICIODropDown.Value;
            TEL=app.TELFONOEditField.Value;
            CONSUM=app.Consumibles.Value;
            [provR,provC] = size(app.proveedoresServicio);
            foundProvSer = [];
            for x = 1 : provR
                if strcmp(PROVSER,app.proveedoresServicio(x,1))
                    foundProvSer = x;
                    break;
                end
            end
            if size(foundProvSer) == 0
               foundProvSer = 1;
            end
            disp(foundProvSer);
                        disp(foundProvSer);
            disp(foundProvSer);
            disp(foundProvSer);

            [areaR,areaC] = size(app.areas);
            foundArea = [];
            for x = 1 : areaR
                if strcmp(AREA,app.areas(x,1))
                    foundArea = x;
                    break;
                end
            end
            if size(foundArea) == 0
               foundArea = 1;
            end
            [ubiR,ubiC] = size(app.ubicaciones);
            foundUbi = [];
            for x = 1 : ubiR
                if strcmp(UBICACION,app.ubicaciones(x,1))
                    foundUbi = x;
                    break;
                end
            end
            if size(foundUbi) == 0
               foundUbi = 1;
            end
             datosinv=0;
                     
            if strcmp(EQUIPO,'')==1
                datosinv=1;
           end
            if strcmp(MARCA,'')==1
                datosinv=1;
           end
            if strcmp(MODELO,'')==1
                datosinv=1;
           end
            if strcmp(NUMSERIE,'')==1
                datosinv=1;
            end
            if datosinv==0
            datos = [string(EQUIPO),string(MARCA), string(MODELO), string(NUMSERIE),string(ACCESORIOS),string(NUMACTFIJO),string(PROVCOM),string(FINST),string(ESTADO),string(REFAC),string(CONSUM),foundProvSer,1, foundUbi,foundArea]
            insertEquipo = sprintf('insert into Equipo(equipo,marca,modelo,numeroSerie,accesorios,numActivoFijo,proveedor,fechaInstal,estado,refaccionesCambiadas,consumibles,proveedorServicio,servicio,ubicacion,area) values ("%s","%s","%s","%s","%s","%s","%s","%s","%s","%s","%s","%s","%s","%s","%s");', datos);
            exec(app.conn,insertEquipo);
            app.mainQuery = sprintf('SELECT Equipo.numControl,Equipo.equipo,Equipo.marca,Equipo.modelo,Equipo.numeroSerie,%s %s %s %s %s %s %s %s %s %s %s %s FROM Equipo JOIN Area ON Equipo.area = Area.id JOIN Ubicacion ON Equipo.ubicacion=Ubicacion.id JOIN ProveedorServicio ON Equipo.proveedorServicio = ProveedorServicio.id;', app.numActivoFijoVisible, app.areaVisible,app.ubicacionVisible,app.proveedorCompraVisible,app.fechaInstalVisible,app.estadoVisible,app.consumiblesVisible,app.accesoriosVisible ,app.refaccionesVisible,app.proveedorServicioVisible,app.contactoVisible,app.telefonoVisible);
            filtrar = fetch(app.conn,app.mainQuery);
            app.Inventario.Data = filtrar;
            close(app.conn);
            dbfile = fullfile(pwd,'Inventario.db');
            app.conn = sqlite(dbfile);
                        aicon=imread('check1.PNG');
                 mh=msgbox('Equipo actualizado exitosamente','Error','custom',aicon);
                th = findall(mh, 'Type', 'Text');                   %get handle to text within msgbox
                th.FontSize = 14;      
                deltaWidth = sum(th.Extent([1,3]))-mh.Position(3) + th.Extent(1);
                deltaHeight = sum(th.Extent([2,4]))-mh.Position(4) + 10;
                mh.Position([3,4]) = mh.Position([3,4]) + [deltaWidth, deltaHeight];
           app.DatosdelproveedordeservicioPanel.Visible='off';
     else 
             aicon=imread('warning.jpg');
                mh=msgbox('Información incompleta, falta llenar campos','Error','custom',aicon);
                th = findall(mh, 'Type', 'Text');                   %get handle to text within msgbox
                th.FontSize = 14;      
                deltaWidth = sum(th.Extent([1,3]))-mh.Position(3) + th.Extent(1);
                deltaHeight = sum(th.Extent([2,4]))-mh.Position(4) + 10;
                mh.Position([3,4]) = mh.Position([3,4]) + [deltaWidth, deltaHeight];
            end  
        end

        % Callback function
        function Button_2Pushed(app, event)
            app.Ypanel=-44;
            app.ProvNombre.Visible=true;
        end

        % Value changed function: AreaCheckBox
        function EsconderArea(app, event)
            value = app.AreaCheckBox.Value;
            if value == 0
               app.areaVisible = '';
               app.Inventario.ColumnName = []

            else
                app.areaVisible = 'Area.nombre,';

            end
            app.mainQuery = sprintf('SELECT Equipo.numControl,Equipo.equipo,Equipo.marca,Equipo.modelo,Equipo.numeroSerie,%s %s %s %s %s %s %s %s %s %s %s %s FROM Equipo JOIN Area ON Equipo.area = Area.id JOIN Ubicacion ON Equipo.ubicacion=Ubicacion.id JOIN ProveedorServicio ON Equipo.proveedorServicio = ProveedorServicio.id;', app.numActivoFijoVisible, app.areaVisible,app.ubicacionVisible,app.proveedorCompraVisible,app.fechaInstalVisible,app.estadoVisible,app.consumiblesVisible,app.accesoriosVisible ,app.refaccionesVisible,app.proveedorServicioVisible,app.contactoVisible,app.telefonoVisible);
            
            filtrar = fetch(app.conn,app.mainQuery);
            app.Inventario.Data = filtrar;
        end

        % Value changed function: ProveedordeCompraCheckBox
        function EsconderProveedorCompra(app, event)
            value = app.ProveedordeCompraCheckBox.Value;
            if value == 0
               app.proveedorCompraVisible = '';
                              app.Inventario.ColumnName = []

            else
                app.proveedorCompraVisible = 'Equipo.Proveedor,';
            end
            app.mainQuery = sprintf('SELECT Equipo.numControl,Equipo.equipo,Equipo.marca,Equipo.modelo,Equipo.numeroSerie,%s %s %s %s %s %s %s %s %s %s %s %s FROM Equipo JOIN Area ON Equipo.area = Area.id JOIN Ubicacion ON Equipo.ubicacion=Ubicacion.id JOIN ProveedorServicio ON Equipo.proveedorServicio = ProveedorServicio.id;', app.numActivoFijoVisible, app.areaVisible,app.ubicacionVisible,app.proveedorCompraVisible,app.fechaInstalVisible,app.estadoVisible,app.consumiblesVisible,app.accesoriosVisible ,app.refaccionesVisible,app.proveedorServicioVisible,app.contactoVisible,app.telefonoVisible);
            
            filtrar = fetch(app.conn,app.mainQuery);
            app.Inventario.Data = filtrar;
        end

        % Value changed function: AccesoriosCheckBox
        function EsconderAccesorios(app, event)
            value = app.AccesoriosCheckBox.Value;
            if value == 0
               app.accesoriosVisible = '';
               app.Inventario.ColumnName = []

               app.Inventario.ColumnName = 'Número de Control,Equipo,Marca,Modelo,Número de Serie,Num Activo Fijo,Área,Ubicación,Proveedor de Compra,Fecha Instalación,Estado,Consumibles,Accesorios,Refacciones Cambiadas,Proveedor de Servicio,Contacto,Teléfono'
               
            else
                app.accesoriosVisible = 'Equipo.accesorios,';
            end
            app.mainQuery = sprintf('SELECT Equipo.numControl,Equipo.equipo,Equipo.marca,Equipo.modelo,Equipo.numeroSerie,%s %s %s %s %s %s %s %s %s %s %s %s FROM Equipo JOIN Area ON Equipo.area = Area.id JOIN Ubicacion ON Equipo.ubicacion=Ubicacion.id JOIN ProveedorServicio ON Equipo.proveedorServicio = ProveedorServicio.id;', app.numActivoFijoVisible, app.areaVisible,app.ubicacionVisible,app.proveedorCompraVisible,app.fechaInstalVisible,app.estadoVisible,app.consumiblesVisible,app.accesoriosVisible ,app.refaccionesVisible,app.proveedorServicioVisible,app.contactoVisible,app.telefonoVisible);
            app.Inventario.ColumnName = []
            filtrar = fetch(app.conn,app.mainQuery);
            app.Inventario.Data = filtrar;
        end

        % Value changed function: NumActivoFijoCheckBox
        function EsconderNumActivo(app, event)
            value = app.NumActivoFijoCheckBox.Value;
            
        end

        % Value changed function: UbicacionCheckBox
        function EsconderUbicacion(app, event)
            value = app.UbicacionCheckBox.Value;
                        if value == 0
               app.ubicacionVisible = '';
                              app.Inventario.ColumnName = []

            else
                app.ubicacionVisible = 'Ubicacion.nombre,';
            end
            app.mainQuery = sprintf('SELECT Equipo.numControl,Equipo.equipo,Equipo.marca,Equipo.modelo,Equipo.numeroSerie,%s %s %s %s %s %s %s %s %s %s %s %s FROM Equipo JOIN Area ON Equipo.area = Area.id JOIN Ubicacion ON Equipo.ubicacion=Ubicacion.id JOIN ProveedorServicio ON Equipo.proveedorServicio = ProveedorServicio.id;', app.numActivoFijoVisible, app.areaVisible,app.ubicacionVisible,app.proveedorCompraVisible,app.fechaInstalVisible,app.estadoVisible,app.consumiblesVisible,app.accesoriosVisible ,app.refaccionesVisible,app.proveedorServicioVisible,app.contactoVisible,app.telefonoVisible);
            
            filtrar = fetch(app.conn,app.mainQuery);
            app.Inventario.Data = filtrar;
        end

        % Value changed function: FechadeInstalacinCheckBox
        function EsconderFecha(app, event)
            value = app.FechadeInstalacinCheckBox.Value;
                        if value == 0
               app.fechaInstalVisible = '';
                              app.Inventario.ColumnName = []

            else
                app.fechaInstalVisible = 'Equipo.fechaInstal,';
            end
            app.mainQuery = sprintf('SELECT Equipo.numControl,Equipo.equipo,Equipo.marca,Equipo.modelo,Equipo.numeroSerie,%s %s %s %s %s %s %s %s %s %s %s %s FROM Equipo JOIN Area ON Equipo.area = Area.id JOIN Ubicacion ON Equipo.ubicacion=Ubicacion.id JOIN ProveedorServicio ON Equipo.proveedorServicio = ProveedorServicio.id;', app.numActivoFijoVisible, app.areaVisible,app.ubicacionVisible,app.proveedorCompraVisible,app.fechaInstalVisible,app.estadoVisible,app.consumiblesVisible,app.accesoriosVisible ,app.refaccionesVisible,app.proveedorServicioVisible,app.contactoVisible,app.telefonoVisible);
            
            filtrar = fetch(app.conn,app.mainQuery);
            app.Inventario.Data = filtrar;
        end

        % Value changed function: EstadodelEquipoCheckBox
        function EsconderEstado(app, event)
            value = app.EstadodelEquipoCheckBox.Value;
                        if value == 0
               app.estadoVisible = '';
                              app.Inventario.ColumnName = []

            else
                app.estadoVisible = 'Equipo.estado,';
            end
            app.mainQuery = sprintf('SELECT Equipo.numControl,Equipo.equipo,Equipo.marca,Equipo.modelo,Equipo.numeroSerie,%s %s %s %s %s %s %s %s %s %s %s %s FROM Equipo JOIN Area ON Equipo.area = Area.id JOIN Ubicacion ON Equipo.ubicacion=Ubicacion.id JOIN ProveedorServicio ON Equipo.proveedorServicio = ProveedorServicio.id;', app.numActivoFijoVisible, app.areaVisible,app.ubicacionVisible,app.proveedorCompraVisible,app.fechaInstalVisible,app.estadoVisible,app.consumiblesVisible,app.accesoriosVisible ,app.refaccionesVisible,app.proveedorServicioVisible,app.contactoVisible,app.telefonoVisible);
            
            filtrar = fetch(app.conn,app.mainQuery);
            app.Inventario.Data = filtrar;
        end

        % Value changed function: ProveedorDeServicioCheckBox
        function EsconderProveedor(app, event)
            value = app.ProveedorDeServicioCheckBox.Value;
                        if value == 0
               app.proveedorServicioVisible = '';
                              app.Inventario.ColumnName = []

            else
                app.proveedorServicioVisible = 'ProveedorServicio.nombre,';
            end
            app.mainQuery = sprintf('SELECT Equipo.numControl,Equipo.equipo,Equipo.marca,Equipo.modelo,Equipo.numeroSerie,%s %s %s %s %s %s %s %s %s %s %s %s FROM Equipo JOIN Area ON Equipo.area = Area.id JOIN Ubicacion ON Equipo.ubicacion=Ubicacion.id JOIN ProveedorServicio ON Equipo.proveedorServicio = ProveedorServicio.id;', app.numActivoFijoVisible, app.areaVisible,app.ubicacionVisible,app.proveedorCompraVisible,app.fechaInstalVisible,app.estadoVisible,app.consumiblesVisible,app.accesoriosVisible ,app.refaccionesVisible,app.proveedorServicioVisible,app.contactoVisible,app.telefonoVisible);
            
            filtrar = fetch(app.conn,app.mainQuery);
            app.Inventario.Data = filtrar;
        end

        % Value changed function: ContactoCheckBox
        function EsconderContacto(app, event)
            value = app.ContactoCheckBox.Value;
                        if value == 0
               app.contactoVisible = '';
                              app.Inventario.ColumnName = []

            else
                app.contactoVisible = 'ProveedorServicio.contacto,';
            end
            app.mainQuery = sprintf('SELECT Equipo.numControl,Equipo.equipo,Equipo.marca,Equipo.modelo,Equipo.numeroSerie,%s %s %s %s %s %s %s %s %s %s %s %s FROM Equipo JOIN Area ON Equipo.area = Area.id JOIN Ubicacion ON Equipo.ubicacion=Ubicacion.id JOIN ProveedorServicio ON Equipo.proveedorServicio = ProveedorServicio.id;', app.numActivoFijoVisible, app.areaVisible,app.ubicacionVisible,app.proveedorCompraVisible,app.fechaInstalVisible,app.estadoVisible,app.consumiblesVisible,app.accesoriosVisible ,app.refaccionesVisible,app.proveedorServicioVisible,app.contactoVisible,app.telefonoVisible);
            
            filtrar = fetch(app.conn,app.mainQuery);
            app.Inventario.Data = filtrar;
        end

        % Value changed function: TelefonoCheckBox
        function EsconderTelefono(app, event)
            value = app.TelefonoCheckBox.Value;
             if value == 0
               app.consumiblesVisible = '';
                              app.Inventario.ColumnName = []

            else
                app.consumiblesVisible = 'Equipo.consumibles,';
            end
            app.mainQuery = sprintf('SELECT Equipo.numControl,Equipo.equipo,Equipo.marca,Equipo.modelo,Equipo.numeroSerie,%s %s %s %s %s %s %s %s %s %s %s %s FROM Equipo JOIN Area ON Equipo.area = Area.id JOIN Ubicacion ON Equipo.ubicacion=Ubicacion.id JOIN ProveedorServicio ON Equipo.proveedorServicio = ProveedorServicio.id;', app.numActivoFijoVisible, app.areaVisible,app.ubicacionVisible,app.proveedorCompraVisible,app.fechaInstalVisible,app.estadoVisible,app.consumiblesVisible,app.accesoriosVisible ,app.refaccionesVisible,app.proveedorServicioVisible,app.contactoVisible,app.telefonoVisible);
            
            filtrar = fetch(app.conn,app.mainQuery);
            app.Inventario.Data = filtrar;
        end

        % Value changed function: RefaccionesCambiadasCheckBox
        function EsconderRefacciones(app, event)
            value = app.RefaccionesCambiadasCheckBox.Value;
            if value == 0
               app.refaccionesVisible = '';
                              app.Inventario.ColumnName = []

            else
                app.refaccionesVisible = 'Equipo.refaccionesCambiadas,';
            end
            app.mainQuery = sprintf('SELECT Equipo.numControl,Equipo.equipo,Equipo.marca,Equipo.modelo,Equipo.numeroSerie,%s %s %s %s %s %s %s %s %s %s %s %s FROM Equipo JOIN Area ON Equipo.area = Area.id JOIN Ubicacion ON Equipo.ubicacion=Ubicacion.id JOIN ProveedorServicio ON Equipo.proveedorServicio = ProveedorServicio.id;', app.numActivoFijoVisible, app.areaVisible,app.ubicacionVisible,app.proveedorCompraVisible,app.fechaInstalVisible,app.estadoVisible,app.consumiblesVisible,app.accesoriosVisible ,app.refaccionesVisible,app.proveedorServicioVisible,app.contactoVisible,app.telefonoVisible);
            
            filtrar = fetch(app.conn,app.mainQuery);
            app.Inventario.Data = filtrar;
        end

        % Button pushed function: REPORTEDEINCIDENTESButton
        function REPORTEDEINCIDENTESButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.REPORTEDEINCIDENTESTab;
            if size(app.EquipoSeleccionado) == 0
                    aicon=imread('warning.jpg');
                    mh=msgbox('No haz seleccionado un Equipo','Error','custom',aicon);
                    th = findall(mh, 'Type', 'Text');                   %get handle to text within msgbox
                    th.FontSize = 14;      
                    deltaWidth = sum(th.Extent([1,3]))-mh.Position(3) + th.Extent(1);
                    deltaHeight = sum(th.Extent([2,4]))-mh.Position(4) + 10;
                    mh.Position([3,4]) = mh.Position([3,4]) + [deltaWidth, deltaHeight];
                    app.TabGroup.SelectedTab = app.BUSCARTab;
                end
        end

        % Button pushed function: GUARDARPROVEEDORDESERVICIOButton
        function GuardarProveedorServicio(app, event)
            
            nombre = app.ProvNombre.Value;
            contacto = app.CONTACTOEditField.Value;
            tel = app.TELFONOEditField.Value;
            datosinv=1;
            if strcmp(nombre,'') ==1
                datosinv=2;
                  a1=' Campo vacío: Nombre';
            end
            if strcmp(contacto,'') ==1
                 datosinv=3;
                  a1=' Campo vacío: Contacto';
              
            %A1=msgbox('Error, escribir en contacto ','Error','custom',a1);
            end
            if strcmp(tel,'') ==1
                datosinv=4;
             a1=' Campo vacío: Teléfono';
           end
            if datosinv==1
            datos = [string(nombre),string(contacto), tel];
            insertProv = sprintf('insert into ProveedorServicio(nombre,contacto,telefono) values ("%s","%s","%s");', datos);
            exec(app.conn,insertProv);
            app.mainQuery = sprintf('SELECT Equipo.numControl,Equipo.equipo,Equipo.marca,Equipo.modelo,Equipo.numeroSerie,%s %s %s %s %s %s %s %s %s %s %s %s FROM Equipo JOIN Area ON Equipo.area = Area.id JOIN Ubicacion ON Equipo.ubicacion=Ubicacion.id JOIN ProveedorServicio ON Equipo.proveedorServicio = ProveedorServicio.id;', app.numActivoFijoVisible, app.areaVisible,app.ubicacionVisible,app.proveedorCompraVisible,app.fechaInstalVisible,app.estadoVisible,app.consumiblesVisible,app.accesoriosVisible ,app.refaccionesVisible,app.proveedorServicioVisible,app.contactoVisible,app.telefonoVisible);
            filtrar = fetch(app.conn,app.mainQuery);
            app.Inventario.Data = filtrar;
            app.proveedoresServicio = fetch(app.conn,'SELECT ProveedorServicio.nombre FROM ProveedorServicio ORDER BY id');
            app.PROVEEDORDESERVICIODropDown.Items = app.proveedoresServicio;
            close(app.conn);
            dbfile = fullfile(pwd,'Inventario.db');

            app.conn = sqlite(dbfile);
            aicon1=imread('check1.PNG');
                                  
                mh=msgbox('Proveedor de servicio guardado exitosamente','Éxito','custom',aicon1);
                th = findall(mh, 'Type', 'Text');                   %get handle to text within msgbox
                th.FontSize = 14;      
                deltaWidth = sum(th.Extent([1,3]))-mh.Position(3) + th.Extent(1);
                deltaHeight = sum(th.Extent([2,4]))-mh.Position(4) + 10;
                mh.Position([3,4]) = mh.Position([3,4]) + [deltaWidth, deltaHeight];
                 app.ProvNombre.Value='';
           app.CONTACTOEditField.Value='';
            app.TELFONOEditField.Value=55;
                
            elseif datosinv==2
                aicon=imread('warning.jpg');
                mh=msgbox(a1,'Error','custom',aicon);
                th = findall(mh, 'Type', 'Text');                   %get handle to text within msgbox
                th.FontSize = 14;      
                deltaWidth = sum(th.Extent([1,3]))-mh.Position(3) + th.Extent(1);
                deltaHeight = sum(th.Extent([2,4]))-mh.Position(4) + 10;
                mh.Position([3,4]) = mh.Position([3,4]) + [deltaWidth, deltaHeight];
      
            elseif datosinv==3
                               aicon=imread('warning.jpg');
                mh=msgbox(a1,'Error','custom',aicon);
                th = findall(mh, 'Type', 'Text');                   %get handle to text within msgbox
                th.FontSize = 14;      
                deltaWidth = sum(th.Extent([1,3]))-mh.Position(3) + th.Extent(1);
                deltaHeight = sum(th.Extent([2,4]))-mh.Position(4) + 10;
                mh.Position([3,4]) = mh.Position([3,4]) + [deltaWidth, deltaHeight];
            else
                             aicon=imread('warning.jpg');
                mh=msgbox(a1,'Error','custom',aicon);
                th = findall(mh, 'Type', 'Text');                   %get handle to text within msgbox
                th.FontSize = 14;      
                deltaWidth = sum(th.Extent([1,3]))-mh.Position(3) + th.Extent(1);
                deltaHeight = sum(th.Extent([2,4]))-mh.Position(4) + 10;
                mh.Position([3,4]) = mh.Position([3,4]) + [deltaWidth, deltaHeight];;
                
            end      

        end

        % Button pushed function: Button_3
        function exportarAExcel(app, event)
            T = cell2table(app.Inventario.Data);
            delete("Inventario.xls");
            writetable(T,'Inventario.xls','WriteVariableNames',0);
             aicon1=imread('check1.PNG');
                                  
                mh=msgbox('Exportado a Excel exitosamente','Éxito','custom',aicon1);
                th = findall(mh, 'Type', 'Text');                   %get handle to text within msgbox
                th.FontSize = 14;      
                deltaWidth = sum(th.Extent([1,3]))-mh.Position(3) + th.Extent(1);
                deltaHeight = sum(th.Extent([2,4]))-mh.Position(4) + 10;
                mh.Position([3,4]) = mh.Position([3,4]) + [deltaWidth, deltaHeight];
        end

        % Cell selection callback: Inventario
        function selectedCell(app, event)
            indices = event.Indices;
            [indR,indC] = size(indices);
            if indR == 1
                valor = app.Inventario.Data{indices}
                app.detalleQuery = sprintf('SELECT Equipo.numControl,Equipo.equipo,Equipo.marca,Equipo.modelo,Equipo.numeroSerie,%s %s %s %s %s %s %s %s %s %s %s %s FROM Equipo JOIN Area ON Equipo.area = Area.id JOIN Ubicacion ON Equipo.ubicacion=Ubicacion.id JOIN ProveedorServicio ON Equipo.proveedorServicio = ProveedorServicio.id WHERE Equipo.numControl = "%s"', app.numActivoFijoVisible, app.areaVisible,app.ubicacionVisible,app.proveedorCompraVisible,app.fechaInstalVisible,app.estadoVisible,app.consumiblesVisible,app.accesoriosVisible ,app.refaccionesVisible,app.proveedorServicioVisible,app.contactoVisible,app.telefonoVisible,string(valor));
                result = fetch(app.conn,app.detalleQuery);
                app.EquipoSeleccionado = valor;
                disp(result)
                nombre = result(1,2);
                app.FechaSolicitud_24.Value = string(nombre);
                marca = result(1,3);
                app.FechaSolicitud_25.Value = string(marca); 
                modelo = result(1,4);
                app.FechaSolicitud_26.Value = string(modelo); 
                numSerie = result(1,5);
                app.FechaSolicitud_27.Value = string(numSerie); 
                area = result(1,7);
                app.AREADropDown_2.Value = area;
                ubi = result(1,8);
                app.UBICACIONDropDown_2.Value = ubi;
                numActivoFijo = result(1,6);
                app.FechaSolicitud_32.Value = string(numActivoFijo); 
                provCompra = result(1,9);
                app.FechaSolicitud_28.Value = string(provCompra); 
                fechaInstal = result(1,10);
                app.FechaSolicitud_30.Value = string(fechaInstal); 
                estado = result(1,11);
                app.FechaSolicitud_31.Value = string(estado); 
                consum = result(1,12);
                app.Consumibles_2.Value = string(consum); 
                accesorios = result(1,13)
                app.FechaSolicitud_29.Value = string(accesorios); 
                refacciones = result(1,14)
                app.REFACCIONESCAMBIADASEditField_2.Value = string(refacciones); 
                proveedorServicio = result(1,15)
                app.PROVEEDORDESERVICIODropDown_2.Value = proveedorServicio;                
                disp(nombre)
                app.ordenNumControl.Text = string(valor);
                app.ordenNumSerie.Text = string(numSerie);
                app.OrdenEquipoNombre.Text = string(nombre);
                app.servicioArea.Text = string(area);
                app.servicioEquipoNombre.Text = string(nombre);
                app.servicioMarca.Text = string(marca);
                app.servicioModelo.Text = string(modelo);
                app.servicioNumSerie.Text = string(numSerie);
                app.servicioNumActivoFlujo.Text = string(numActivoFijo);
                app.servicioUbicacion.Text = string(ubi);
                app.servicioProveedorCompra.Text = string(provCompra);
                app.servicioProveedorServicio.Text = string(proveedorServicio);
                countOrdenesServicio = str2num(string(fetch(app.conn,'SELECT COUNT(*) FROM OrdenServicio')));
                app.ordenNumControl_2.Text = string(valor);
                app.ordenNumSerie_2.Text = string(numSerie);
                app.OrdenEquipoNombre_2.Text = string(nombre);
                disp(countOrdenesServicio)
                if countOrdenesServicio > 0 
                    ordenesServicio = str2num(string(fetch(app.conn,'SELECT MAX(OrdenServicio.folio) FROM OrdenServicio'))) + 1;
                    app.OrdenFolio.Text = string(ordenesServicio);
                else 
                    app.OrdenFolio.Text = '1';
                end
            mantenimientos = fetch(app.conn, sprintf(' SELECT Servicio.id, Servicio.tipo,Servicio.estado,Servicio.fecha from Servicio WHERE Servicio.Equipo = "%s"',string(valor)));
           disp(mantenimientos)
            app.tablaMantenimiento.Data = mantenimientos;
            app.tablaOrdenesServicio.Data = fetch(app.conn,sprintf('Select  folio,nombreArchivo,fecha from OrdenServicio where Equipo = %s',string(app.EquipoSeleccionado)));
            app.tablaIncidentes.Data = fetch(app.conn, sprintf('Select id,nombreArchivo,fechaIncidente FROM Incidente WHERE Incidente.Equipo = "%s"', string(app.EquipoSeleccionado)))

            disp("FSDLAJFASDJLFLASJDF")
            disp(mantenimientos)
            end
        end

        % Button pushed function: 
        % ActualizarInformacindelEquipoButton
        function actualizarEquipo(app, event)
            EQUIPO= app.FechaSolicitud_24.Value;
            MARCA=app.FechaSolicitud_25.Value;
            MODELO= app.FechaSolicitud_26.Value;
            NUMSERIE = app.FechaSolicitud_27.Value;
            ESTADO = app.FechaSolicitud_31.Value;
            PROVCOM=app.FechaSolicitud_28.Value;
            ACCESORIOS=app.FechaSolicitud_29.Value;
            NUMACTFIJO=app.FechaSolicitud_32.Value;
            UBICACION=app.UBICACIONDropDown_2.Value;
            FINST=app.FechaSolicitud_30.Value;
            PROVSER=app.PROVEEDORDESERVICIODropDown_2.Value;
            AREA=app.AREADropDown_2.Value;
            REFAC = app.REFACCIONESCAMBIADASEditField_2.Value;
            PROVEEDOR = app.PROVEEDORDESERVICIODropDown_2.Value;
            CONSUM=app.Consumibles_2.Value;
            [provR,provC] = size(app.proveedoresServicio);
            foundProvSer = [];
            for x = 1 : provR
                if strcmp(PROVSER,app.proveedoresServicio(x,1))
                    foundProvSer = x;
                    break;
                end
            end
            if size(foundProvSer) == 0
               foundProvSer = 1;
            end
            disp(foundProvSer);
                        disp(foundProvSer);
            disp(foundProvSer);
            disp(foundProvSer);

            [areaR,areaC] = size(app.areas);
            foundArea = [];
            for x = 1 : areaR
                if strcmp(AREA,app.areas(x,1))
                    foundArea = x;
                    break;
                end
            end
            if size(foundArea) == 0
               foundArea = 1;
            end
            [ubiR,ubiC] = size(app.ubicaciones);
            foundUbi = [];
            for x = 1 : ubiR
                if strcmp(UBICACION,app.ubicaciones(x,1))
                    foundUbi = x;
                    break;
                end
            end
            if size(foundUbi) == 0
               foundUbi = 1;
            end
              if size(foundUbi) == 0
               foundUbi = 1;
            end
            datosinv=0;
                     
            if strcmp(EQUIPO,'')==1
                datosinv=1;
           end
            if strcmp(MARCA,'')==1
                datosinv=1;
           end
            if strcmp(MODELO,'')==1
                datosinv=1;
           end
            if strcmp(NUMSERIE,'')==1
                datosinv=1;
           end
      if datosinv==0
                        
             numControl = app.EquipoSeleccionado
             deleteQuery = sprintf('DELETE FROM Equipo WHERE Equipo.numControl = %d',numControl);
             exec(app.conn,deleteQuery)
             datos = [numControl,string(EQUIPO),string(MARCA), string(MODELO), string(NUMSERIE),string(ACCESORIOS),string(NUMACTFIJO),string(PROVCOM),string(FINST),string(ESTADO),string(REFAC),string(CONSUM),foundProvSer,1, foundUbi,foundArea]
             insertEquipo = sprintf('insert into Equipo(numControl,equipo,marca,modelo,numeroSerie,accesorios,numActivoFijo,proveedor,fechaInstal,estado,refaccionesCambiadas,consumibles,proveedorServicio,servicio,ubicacion,area) values ("%s","%s","%s","%s","%s","%s","%s","%s","%s","%s","%s","%s","%s","%s","%s","%s");', datos);
             exec(app.conn,insertEquipo);
             app.mainQuery = sprintf('SELECT Equipo.numControl,Equipo.equipo,Equipo.marca,Equipo.modelo,Equipo.numeroSerie,%s %s %s %s %s %s %s %s %s %s %s %s FROM Equipo JOIN Area ON Equipo.area = Area.id JOIN Ubicacion ON Equipo.ubicacion=Ubicacion.id JOIN ProveedorServicio ON Equipo.proveedorServicio = ProveedorServicio.id;', app.numActivoFijoVisible, app.areaVisible,app.ubicacionVisible,app.proveedorCompraVisible,app.fechaInstalVisible,app.estadoVisible,app.consumiblesVisible,app.accesoriosVisible ,app.refaccionesVisible,app.proveedorServicioVisible,app.contactoVisible,app.telefonoVisible);
             filtrar = fetch(app.conn,app.mainQuery);
             app.Inventario.Data = filtrar;
            %whereClause = sprintf('where Equipo.numControl = %s', app.EquipoSeleccionado);
            %update(app.conn,'Equipo',{'equipo','marca','modelo','numeroSerie','accesorios','numActivoFijo','proveedor','fechaInstal','estado','refaccionesCambiadas','consumibles','proveedorServicio','servicio','ubicacion','area'},datos,whereClause);
 %A2=msgbox('Información del equipo actualizada exitosamente','Éxito','custom',a1);
            close(app.conn);
            dbfile = fullfile(pwd,'Inventario.db');
            app.conn = sqlite(dbfile);
                  aicon=imread('check1.PNG');
                 mh=msgbox('Equipo actualizado exitosamente','Error','custom',aicon);
                th = findall(mh, 'Type', 'Text');                   %get handle to text within msgbox
                th.FontSize = 14;      
                deltaWidth = sum(th.Extent([1,3]))-mh.Position(3) + th.Extent(1);
                deltaHeight = sum(th.Extent([2,4]))-mh.Position(4) + 10;
                mh.Position([3,4]) = mh.Position([3,4]) + [deltaWidth, deltaHeight];
     else 
             aicon=imread('warning.jpg');
                mh=msgbox('Información incompleta, falta llenar campos','Error','custom',aicon);
                th = findall(mh, 'Type', 'Text');                   %get handle to text within msgbox
                th.FontSize = 14;      
                deltaWidth = sum(th.Extent([1,3]))-mh.Position(3) + th.Extent(1);
                deltaHeight = sum(th.Extent([2,4]))-mh.Position(4) + 10;
                mh.Position([3,4]) = mh.Position([3,4]) + [deltaWidth, deltaHeight];
      end
      
        end

        % Button pushed function: GUARDARINGENIEROButton
        function GuardarIngeniero(app, event)
            nombre = app.IngenieroNombre.Value;
            experiencia = app.IngenieroExp.Value;
            capacitacion = app.IngenieroCapacitaciones.Value;
            datos = [string(nombre),string(experiencia), string(capacitacion)]
            insertInge = sprintf('insert into Ingeniero(nombre,experiencia,capacitacion) values ("%s","%s","%s");', datos);
            if strcmp(string(nombre),"") == 0
             try
                exec(app.conn,insertInge);
                app.IngenieroTabla.Data = fetch(app.conn,'Select Ingeniero.nombre, Ingeniero.experiencia, Ingeniero.capacitacion FROM Ingeniero ORDER BY Ingeniero.nombre');
                 aicon=imread('check1.PNG');
                 mh=msgbox('Ingeniero agregado exitosamente','Error','custom',aicon);
                th = findall(mh, 'Type', 'Text');                   %get handle to text within msgbox
                th.FontSize = 14;      
                deltaWidth = sum(th.Extent([1,3]))-mh.Position(3) + th.Extent(1);
                deltaHeight = sum(th.Extent([2,4]))-mh.Position(4) + 10;
                mh.Position([3,4]) = mh.Position([3,4]) + [deltaWidth, deltaHeight];
                app.ingenieros = fetch(app.conn,'SELECT Ingeniero.nombre from Ingeniero');
                app.ASIGNADOADropDown.Items = app.ingenieros;
            catch ME
                disp(ME)
               a1=imread('warning.jpg');
                mh=msgbox('Error, Ingeniero ya Existe','Error','custom',a1);
                th = findall(mh, 'Type', 'Text');                   %get handle to text within msgbox
                th.FontSize = 16;      
                deltaWidth = sum(th.Extent([1,3]))-mh.Position(3) + th.Extent(1);
                deltaHeight = sum(th.Extent([2,4]))-mh.Position(4) + 10;
                mh.Position([3,4]) = mh.Position([3,4]) + [deltaWidth, deltaHeight];
            end
            close(app.conn);
            dbfile = fullfile(pwd,'Inventario.db');
            app.conn = sqlite(dbfile);
               
            else 
             aicon=imread('warning.jpg');
                mh=msgbox('Falta llenar el nombre','Error','custom',aicon);
                th = findall(mh, 'Type', 'Text');                   %get handle to text within msgbox
                th.FontSize = 14;      
                deltaWidth = sum(th.Extent([1,3]))-mh.Position(3) + th.Extent(1);
                deltaHeight = sum(th.Extent([2,4]))-mh.Position(4) + 10;
                mh.Position([3,4]) = mh.Position([3,4]) + [deltaWidth, deltaHeight];
            end  
        end

        % Value changed function: IngenieroBuscar
        function buscarIngeniero(app, event)
            value = app.IngenieroBuscar.Value;
            app.IngenieroTabla.Data = fetch(app.conn,sprintf('Select Ingeniero.nombre, Ingeniero.experiencia, Ingeniero.capacitacion FROM Ingeniero WHERE Ingeniero.experiencia = "%%%s%%" or Ingeniero.nombre LIKE "%%%s%%" or Ingeniero.capacitacion = "%%%s%%" ORDER BY Ingeniero.nombre', app.IngenieroBuscar.Value,app.IngenieroBuscar.Value,app.IngenieroBuscar.Value));
            
        end

        % Button pushed function: Button_4
        function MostrarProveedorServ(app, event)
                    app.Ypanel=-44;
            app.DatosdelproveedordeservicioPanel.Visible='on';
            app.ProvNombre.Visible=true;
        end

        % Button pushed function: DETALLESDESERVICIOButton
        function DETALLESDESERVICIOButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.DETALLEDELEQUIPOTab;
            if size(app.EquipoSeleccionado) == 0
                    aicon=imread('warning.jpg');
                    mh=msgbox('No haz seleccionado un Equipo','Error','custom',aicon);
                    th = findall(mh, 'Type', 'Text');                   %get handle to text within msgbox
                    th.FontSize = 14;      
                    deltaWidth = sum(th.Extent([1,3]))-mh.Position(3) + th.Extent(1);
                    deltaHeight = sum(th.Extent([2,4]))-mh.Position(4) + 10;
                    mh.Position([3,4]) = mh.Position([3,4]) + [deltaWidth, deltaHeight];
                    app.TabGroup.SelectedTab = app.BUSCARTab;
                end
        end

        % Button pushed function: CALENDARIZACIONButton
        function CALENDARIZACIONButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.CALENDARIOTab;
        end

        % Button pushed function: CALENDARIZARMANTENIMIENTOButton
        function CALENDARIZARMANTENIMIENTOButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.CALENDARIZARMANTENIMIENTOTab;
            if size(app.EquipoSeleccionado) == 0
                    aicon=imread('warning.jpg');
                    mh=msgbox('No haz seleccionado un Equipo','Error','custom',aicon);
                    th = findall(mh, 'Type', 'Text');                   %get handle to text within msgbox
                    th.FontSize = 14;      
                    deltaWidth = sum(th.Extent([1,3]))-mh.Position(3) + th.Extent(1);
                    deltaHeight = sum(th.Extent([2,4]))-mh.Position(4) + 10;
                    mh.Position([3,4]) = mh.Position([3,4]) + [deltaWidth, deltaHeight];
                    app.TabGroup.SelectedTab = app.BUSCARTab;
                end
        end

        % Button pushed function: REGISTRODEINGENIEROSButton
        function REGISTRODEINGENIEROSButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.INGENIEROSTab;
        end

        % Button pushed function: AGENDARServicioButton
        function GuardarServicio(app, event)
             numControl = app.EquipoSeleccionado
             fecha = app.MantenimientoFecha.Value
             correctivo = app.MantenimientoCorrectivoButton.Value
             preventivo = app.MantenimientoPreventivoButton.Value
             if correctivo == 1
                 tipo = 'Correctivo'
             else 
                 tipo = 'Preventivo'
             end
             programado = app.ProgramadoButton.Value
             if programado == 1 
                 estado = 'Programado'
             else
                 estado = 'Realizado'
             end

               if ~ismissing(fecha)
                   datos = [string(app.EquipoSeleccionado),string(estado),string(tipo),string(fecha)]
                insertServicio = sprintf('insert into Servicio(Equipo,estado,tipo,fecha) values ("%s","%s","%s","%s");', datos);
                exec(app.conn,insertServicio);
                close(app.conn);
                 dbfile = fullfile(pwd,'Inventario.db');
                             app.conn = sqlite(dbfile);

                 aicon=imread('check1.PNG');
                 mh=msgbox('Mantenimiento agregado exitosamente','Error','custom',aicon);
                th = findall(mh, 'Type', 'Text');                   %get handle to text within msgbox
                th.FontSize = 14;      
                deltaWidth = sum(th.Extent([1,3]))-mh.Position(3) + th.Extent(1);
                deltaHeight = sum(th.Extent([2,4]))-mh.Position(4) + 10;
                mh.Position([3,4]) = mh.Position([3,4]) + [deltaWidth, deltaHeight];
                app.TabGroup.SelectedTab=app.DETALLEDELEQUIPOTab;

            else 
             aicon=imread('warning.jpg');
                mh=msgbox('falta llenar fecha de Mantenimiento','Error','custom',aicon);
                th = findall(mh, 'Type', 'Text');                   %get handle to text within msgbox
                th.FontSize = 14;      
                deltaWidth = sum(th.Extent([1,3]))-mh.Position(3) + th.Extent(1);
                deltaHeight = sum(th.Extent([2,4]))-mh.Position(4) + 10;
                mh.Position([3,4]) = mh.Position([3,4]) + [deltaWidth, deltaHeight];
            end  
            mantenimientos = fetch(app.conn, sprintf(' SELECT Servicio.id, Servicio.tipo,Servicio.estado,Servicio.fecha from Servicio WHERE Servicio.Equipo = "%s"',string(app.EquipoSeleccionado)));
            app.tablaMantenimiento.Data = mantenimientos;
        end

        % Button pushed function: GuardarOrdenBoton
        function GuardarOrdenServicio(app, event)
         no_contr=app.ordenNumControl.Text;
         no_ser=app.ordenNumSerie.Text;
         equi=app.OrdenEquipoNombre.Text;
         foliogenser=app.OrdenFolio.Text;
         fechsol=app.FechaSolicitud.Value
         fechter=app.FechaTerminacion.Value
         depto= app.Depto.Value;
         falla=app.Falla.Value;
         quienrep=app.QuienReporta.Value;
         quienrecib= app.QuienRecibe.Value;
         asignado=app.ASIGNADOADropDown.Value;
         trealizado= app.TrabajoHecho.Value;
         pnuevas=app.Refacciones.Value;
         obser=app.Observaciones.Value;
         horas=app.HORASINGENIEROEditField.Value;
         TotalHoras=app.TotalHoras.Value;
         costrefcc=app.CostoRefacciones.Value;
         costserext=app.CostoServExterno.Value;
         recipor=app.RecibidoPor.Value;
         coment=app.Comentarios.Value;
         fecharec=app.fechaOrdenServicio.Value;
         totalcost=app.Total.Value;
         eqdefe=app.EquipoDefectuosoButton.Value;
         malop=app.MalaOperacionButton.Value;
         asestec=app.AsesoraTecnicaButton.Value;
         if eqdefe==1
             fallaencont="Equipo Defectuoso";
         elseif malop==1
             fallaencont="Mala Operación";
         else 
             fallaencont="Asesoría Técnica";
         end
         manttprev=app.ManttoPreventivoButton.Value;
         manttocorr=app.ManttoCorrectivoButton.Value;
         asesserv=app.AsesoraenServicioButton.Value;
         servexter=app.ServicioExternoButton.Value;
         if manttprev==1
             trabrealizado='Trabajo Realizado';
         elseif  manttocorr==1
             trabrealizado='Mantenimiento Correctivo';
         elseif asesserv==1
             trabrealizado='Asesor en Servicio';
         else 
             trabrealizado='Servicio Externo';
                      
         end    
          if   ~ismissing(no_contr)
           else
               aicon=imread('warning.jpg');
                mh=msgbox('No se ha seleccionado un Equipo','Error','custom',aicon);
                th = findall(mh, 'Type', 'Text');                   %get handle to text within msgbox
                th.FontSize = 14;      
                deltaWidth = sum(th.Extent([1,3]))-mh.Position(3) + th.Extent(1);
                deltaHeight = sum(th.Extent([2,4]))-mh.Position(4) + 10;
                mh.Position([3,4]) = mh.Position([3,4]) + [deltaWidth, deltaHeight];
                return;
           end

           import mlreportgen.dom.Document
           if ~ismissing(foliogenser)
                          app.ImprimirOrdenBoton.Visible=true;
           app.ImprimirOrdenBoton.Enable=true;
           if ~exist('OrdenesDeServicio', 'dir')
                mkdir('OrdenesDeServicio')
           end
           nombreArchivo = sprintf('OrdenesDeServicio/Equipo%sSolicitud%s.docx',no_contr,string(foliogenser))
           folio = foliogenser;
           fecha = string(fechsol);
           equipo = string(app.EquipoSeleccionado)
           datos = [fecha,nombreArchivo,equipo]
           if   ~ismissing(fechsol)
           else
               aicon=imread('warning.jpg');
                mh=msgbox('No Se ha seleccionado una Fecha','Error','custom',aicon);
                th = findall(mh, 'Type', 'Text');                   %get handle to text within msgbox
                th.FontSize = 14;      
                deltaWidth = sum(th.Extent([1,3]))-mh.Position(3) + th.Extent(1);
                deltaHeight = sum(th.Extent([2,4]))-mh.Position(4) + 10;
                mh.Position([3,4]) = mh.Position([3,4]) + [deltaWidth, deltaHeight];
                return;
           end
           insertOrden = sprintf('insert into OrdenServicio(fecha,nombreArchivo,Equipo) values ("%s","%s", "%s");', datos);
           exec(app.conn,insertOrden);
           app.tablaOrdenesServicio.Data = fetch(app.conn,sprintf('Select  folio,nombreArchivo,fecha from OrdenServicio where Equipo = %s',equipo));
           aicon=imread('check1.png');
                mh=msgbox('Se ha guardado la Orden de servicio','Error','custom',aicon);
                th = findall(mh, 'Type', 'Text');                   %get handle to text within msgbox
                th.FontSize = 14;      
                deltaWidth = sum(th.Extent([1,3]))-mh.Position(3) + th.Extent(1);
                deltaHeight = sum(th.Extent([2,4]))-mh.Position(4) + 10;
                mh.Position([3,4]) = mh.Position([3,4]) + [deltaWidth, deltaHeight];
                D=Document(nombreArchivo,'docx','OrdenServicioPlantilla.dotx');
           open(D)
           moveToNextHole(D)
           if  strcmp(no_contr,'') ~= 1
               append(D, no_contr)
           else
               append(D, " ")
           end
           moveToNextHole(D)
           if  strcmp(no_ser,'') ~= 1
               append(D, no_ser)
           else
               append(D, " ")
           end
           moveToNextHole(D)
           if  strcmp(equi,'') ~= 1
               append(D, equi)
           else
               append(D, " ")
           end
           moveToNextHole(D)
           if  strcmp(foliogenser,'') ~= 1
               append(D, foliogenser)
           else
               append(D, " ")
           end
           moveToNextHole(D)
           if   ~ismissing(fechsol)
               append(D, string(fechsol))
           else
               aicon=imread('warning.jpg');
                mh=msgbox('No se ha seleccionado una Fecha','Error','custom',aicon);
                th = findall(mh, 'Type', 'Text');                   %get handle to text within msgbox
                th.FontSize = 14;      
                deltaWidth = sum(th.Extent([1,3]))-mh.Position(3) + th.Extent(1);
                deltaHeight = sum(th.Extent([2,4]))-mh.Position(4) + 10;
                mh.Position([3,4]) = mh.Position([3,4]) + [deltaWidth, deltaHeight];
                return;
                append(D, " ")
           end
           moveToNextHole(D)
           if  ~ismissing(fechter)
               append(D, string(fechter))
           else
               append(D, " ")
           end
           moveToNextHole(D)
           if  strcmp(depto,'') ~= 1
               append(D, depto)
           else
               append(D, " ")
           end
           moveToNextHole(D)
           if  strcmp(quienrep,'') ~= 1
               append(D, quienrep)
           else
               append(D, " ")
           end
           moveToNextHole(D)
           if  strcmp(falla,'') ~= 1
               append(D, falla)
           else
               append(D, " ")
           end
           moveToNextHole(D)
           if  strcmp(quienrecib,'') ~= 1
               append(D, quienrecib)
           else
               append(D, " ")
           end
           moveToNextHole(D)
           if  strcmp(asignado,'') ~= 1
               append(D, asignado)
           else
               append(D, " ")
           end
           moveToNextHole(D)
           if  strcmp(trealizado,'') ~= 1
               append(D, trealizado)
           else
               append(D, " ")
           end
           moveToNextHole(D)
           if  strcmp(fallaencont,'') ~= 1
               append(D, fallaencont)
           else
               append(D, " ")
           end
           moveToNextHole(D)
           if  strcmp(pnuevas,'') ~= 1
               append(D, pnuevas)
           else
               append(D, " ")
           end
           moveToNextHole(D)
           if  strcmp(trabrealizado,'') ~= 1
               append(D, trabrealizado)
           else
               append(D, " ")
           end
           moveToNextHole(D)
           if  strcmp(obser,'') ~= 1
               append(D, obser)
           else
               append(D, " ")
           end
           moveToNextHole(D)
           if  strcmp(horas,'') ~= 1
               append(D, horas)
           else
               append(D, " ")
           end
           moveToNextHole(D)
           if  strcmp(TotalHoras,'') ~= 1
               append(D, TotalHoras)
           else
               append(D, " ")
           end
           moveToNextHole(D)
           if  strcmp(totalcost,'') ~= 1
               append(D, totalcost)
           else
               append(D, " ")
           end
           moveToNextHole(D)
           if  strcmp(costrefcc,'') ~= 1
               append(D, costrefcc)
           else
               append(D, " ")
           end
           moveToNextHole(D)
           if  strcmp(costserext,'') ~= 1
               append(D, costserext)
           else
               append(D, " ")
           end
           moveToNextHole(D)          
           if  strcmp(recipor,'') ~= 1
               append(D, recipor)
           else
               append(D, " ")
           end
           moveToNextHole(D)
           if  ~ismissing(fecharec)
               append(D, string(fecharec))
           else
               append(D, " ")
           end
           moveToNextHole(D)
           if  strcmp(coment,'') ~= 1
               append(D, coment)
           else
               append(D, " ")
           end
           close(D)
           else
               aicon=imread('warning.jpg');
                mh=msgbox('No se ha seleccionado un Equipo','Error','custom',aicon);
                th = findall(mh, 'Type', 'Text');                   %get handle to text within msgbox
                th.FontSize = 14;      
                deltaWidth = sum(th.Extent([1,3]))-mh.Position(3) + th.Extent(1);
                deltaHeight = sum(th.Extent([2,4]))-mh.Position(4) + 10;
                mh.Position([3,4]) = mh.Position([3,4]) + [deltaWidth, deltaHeight];
           end
           app.TabGroup.SelectedTab=app.DETALLEDELEQUIPOTab;
        end

        % Button pushed function: EditarMantenimientoButton
        function EditarMantenimiento(app, event)
       app.ActualizarinformacindelmantenimientoPanel.Visible = 1
        end

        % Cell selection callback: tablaMantenimiento
        function seleccionEditarMantenimiento(app, event)
            indices = event.Indices;
            [indR,indC] = size(indices);
            if indR == 1
                valor = app.tablaMantenimiento.Data{indices}
                app.mantenimientoSeleccionado = string(valor);
                app.EditarMantenimientoButton.Enable = 1
           
            end
        end

        % Button pushed function: ACTUALIZARButton
        function ActualizarServicio(app, event)
           app.ActualizarinformacindelmantenimientoPanel.Visible = 1

             
            fecha = app.ActualizarMantenimientoFecha.Value
            correctivo = app.MantenimientoCorrectivoButton_2.Value
            preventivo = app.MantenimientoPreventivoButton_2.Value
            if correctivo == 1
                 tipo = 'Correctivo'
             else 
                 tipo = 'Preventivo'
             end
             programado = app.ProgramadoButton_2.Value
             if programado == 1 
                 estado = 'Programado'
             else
                 estado = 'Realizado'
             end

               if ~ismissing(fecha)
             deleteQuery = sprintf('DELETE FROM Servicio WHERE Servicio.id = %s',app.mantenimientoSeleccionado);
             exec(app.conn,deleteQuery)
                   datos = [string(app.EquipoSeleccionado),string(estado),string(tipo),string(fecha),string(app.mantenimientoSeleccionado)]
                insertServicio = sprintf('insert into Servicio(Equipo,estado,tipo,fecha,id) values ("%s","%s","%s","%s","%s");', datos);
                exec(app.conn,insertServicio);
                close(app.conn);
                 dbfile = fullfile(pwd,'Inventario.db');
                             app.conn = sqlite(dbfile);

                 aicon=imread('check1.PNG');
                 mh=msgbox('Mantenimiento actualizado exitosamente','Error','custom',aicon);
                th = findall(mh, 'Type', 'Text');                   %get handle to text within msgbox
                th.FontSize = 14;      
                deltaWidth = sum(th.Extent([1,3]))-mh.Position(3) + th.Extent(1);
                deltaHeight = sum(th.Extent([2,4]))-mh.Position(4) + 10;
                mh.Position([3,4]) = mh.Position([3,4]) + [deltaWidth, deltaHeight];
            else 
             aicon=imread('warning.jpg');
                mh=msgbox('falta llenar fecha de Mantenimiento','Error','custom',aicon);
                th = findall(mh, 'Type', 'Text');                   %get handle to text within msgbox
                th.FontSize = 14;      
                deltaWidth = sum(th.Extent([1,3]))-mh.Position(3) + th.Extent(1);
                deltaHeight = sum(th.Extent([2,4]))-mh.Position(4) + 10;
                mh.Position([3,4]) = mh.Position([3,4]) + [deltaWidth, deltaHeight];
            end  
            mantenimientos = fetch(app.conn, sprintf(' SELECT Servicio.id, Servicio.tipo,Servicio.estado,Servicio.fecha from Servicio WHERE Servicio.Equipo = "%s"',string(app.EquipoSeleccionado)));
            app.tablaMantenimiento.Data = mantenimientos;
        end

        % Button pushed function: BorrarEquipoButton
        function borrarEquipo(app, event)
            numControl = app.EquipoSeleccionado
             deleteQuery = sprintf('DELETE FROM Equipo WHERE Equipo.numControl = %d',numControl);
             exec(app.conn,deleteQuery)
             close(app.conn);
            dbfile = fullfile(pwd,'Inventario.db');
            app.conn = sqlite(dbfile);
             aicon=imread('check1.PNG');
              mh=msgbox('Equipo Eliminado exitosamente','Error','custom',aicon);
                th = findall(mh, 'Type', 'Text');                   %get handle to text within msgbox
                th.FontSize = 14;      
                deltaWidth = sum(th.Extent([1,3]))-mh.Position(3) + th.Extent(1);
                deltaHeight = sum(th.Extent([2,4]))-mh.Position(4) + 10;
                mh.Position([3,4]) = mh.Position([3,4]) + [deltaWidth, deltaHeight];
                app.mainQuery = sprintf('SELECT Equipo.numControl,Equipo.equipo,Equipo.marca,Equipo.modelo,Equipo.numeroSerie,%s %s %s %s %s %s %s %s %s %s %s %s FROM Equipo JOIN Area ON Equipo.area = Area.id JOIN Ubicacion ON Equipo.ubicacion=Ubicacion.id JOIN ProveedorServicio ON Equipo.proveedorServicio = ProveedorServicio.id;', app.numActivoFijoVisible, app.areaVisible,app.ubicacionVisible,app.proveedorCompraVisible,app.fechaInstalVisible,app.estadoVisible,app.consumiblesVisible,app.accesoriosVisible ,app.refaccionesVisible,app.proveedorServicioVisible,app.contactoVisible,app.telefonoVisible);

            inventario = fetch(app.conn,app.mainQuery);

            app.Inventario.Data = inventario;
           
                app.TabGroup.SelectedTab=app.BUSCARTab;
                
            
        end

        % Cell selection callback: tablaOrdenesServicio
        function ordenSeleccionada(app, event)
            indices = event.Indices;
            [indR,indC] = size(indices);
            if indR == 1
                valor = app.tablaOrdenesServicio.Data{indices}
                app.ordenServicioSeleccionada = string(valor);
                app.EditarOrdendeServicioButton.Enable = 1

            end
        end

        % Button pushed function: EditarOrdendeServicioButton
        function editarOrdenServicio(app, event)
            queryNombreArchivo = sprintf('select nombreArchivo from OrdenServicio where folio ="%s"',string(app.ordenServicioSeleccionada))
            try 
               winopen(string(fetch(app.conn,queryNombreArchivo)))

            catch ME
                 aicon=imread('warning.jpg');
                mh=msgbox('El archivo no se encontró, fue borrado manualmente por algún usuario','Error','custom',aicon);
                th = findall(mh, 'Type', 'Text');                   %get handle to text within msgbox
                th.FontSize = 14;      
                deltaWidth = sum(th.Extent([1,3]))-mh.Position(3) + th.Extent(1);
                deltaHeight = sum(th.Extent([2,4]))-mh.Position(4) + 10;
                mh.Position([3,4]) = mh.Position([3,4]) + [deltaWidth, deltaHeight];
            end

        end

        % Value changed function: Buscarporedit_2
        function filtrar2(app, event)
            value = app.Buscarporedit_2.Value;
            buscar = sprintf('SELECT Equipo.numControl,Equipo.equipo,Equipo.marca,Equipo.modelo,Equipo.numeroSerie,Equipo.numActivoFijo,Area.nombre,Ubicacion.nombre,Equipo.proveedor,Equipo.fechaInstal,Equipo.estado,Equipo.consumibles,Equipo.accesorios,Equipo.refaccionesCambiadas,ProveedorServicio.nombre,ProveedorServicio.contacto,ProveedorServicio.telefono FROM Equipo JOIN Area ON Equipo.area = Area.id JOIN Ubicacion ON Equipo.ubicacion=Ubicacion.id JOIN ProveedorServicio ON Equipo.proveedorServicio = ProveedorServicio.id WHERE %s.%s LIKE "%s%%";',tabla,app.tipoFiltro,value);
        end

        % Button pushed function: FiltrarButton
        function filtrarCalendario(app, event)
          mes = string(app.CalendarioMes.Value)
          year = string(app.CalendarioYear.Value)
          equiposYMantenimientos = fetch(app.conn,sprintf('Select Equipo.equipo,Equipo.modelo,Servicio.tipo,Servicio.estado,Servicio.fecha from Equipo JOIN Servicio ON Equipo.numControl = Servicio.Equipo WHERE Servicio.fecha LIKE "%%%s/%s%%"',mes,year));
          disp(equiposYMantenimientos)
          app.TablaCalendario.Data = equiposYMantenimientos  
        end

        % Button pushed function: RemoverFiltroButton
        function removerCalendarioFiltro(app, event)
            equiposYMantenimientos = fetch(app.conn,'Select Equipo.equipo,Equipo.modelo,Servicio.tipo,Servicio.estado,Servicio.fecha from Equipo JOIN Servicio ON Equipo.numControl = Servicio.Equipo');
            disp(equiposYMantenimientos)
            app.TablaCalendario.Data = equiposYMantenimientos
        end

        % Selection change function: TabGroup
        function checarSiEquipoSeleccionado(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if selectedTab == app.DETALLEDELEQUIPOTab || selectedTab == app.ORDENDESERVICIOTab || selectedTab == app.REPORTEDEINCIDENTESTab || selectedTab == app.CALENDARIZARMANTENIMIENTOTab 
                if size(app.EquipoSeleccionado) == 0
                    aicon=imread('warning.jpg');
                    mh=msgbox('No haz seleccionado un Equipo','Error','custom',aicon);
                    th = findall(mh, 'Type', 'Text');                   %get handle to text within msgbox
                    th.FontSize = 14;      
                    deltaWidth = sum(th.Extent([1,3]))-mh.Position(3) + th.Extent(1);
                    deltaHeight = sum(th.Extent([2,4]))-mh.Position(4) + 10;
                    mh.Position([3,4]) = mh.Position([3,4]) + [deltaWidth, deltaHeight];
                    app.TabGroup.SelectedTab = app.BUSCARTab;
                end
            end
            app.EditarMantenimientoButton.Enable = 0
            app.EditarOrdendeServicioButton.Enable = 0
            app.EditarReportedeIncidentesButton.Enable = 0
        end

        % Button pushed function: GuardarIncidenteButton
        function guardarIncidente(app, event)
           no_contr2=app.ordenNumControl_2.Text;
           no_ser2=app.ordenNumSerie_2.Text;
           equi2=app.OrdenEquipoNombre_2.Text;
            Nombre1=app.Asignado_8.Value;
           Puesto1=app.Asignado_12.Value;
           Antiguedad1=app.Asignado_16.Value;
           Nombre2=app.Asignado_9.Value;
           Puesto2=app.Asignado_13.Value;
           Antiguedad2=app.Asignado_17.Value;
           Nombre3=app.Asignado_10.Value;
           Puesto3=app.Asignado_14.Value;
           Antiguedad3=app.Asignado_18.Value;
           Nombre4=app.Asignado_11.Value;
           Puesto4=app.Asignado_15.Value;
           Antiguedad4=app.Asignado_19.Value;
           Situacion=app.CostoServExterno_3.Value;
           Background=app.CostoServExterno_4.Value;
           AccionesInternas=app.CostoServExterno_5.Value;
           Recomendaciones=app.CostoServExterno_6.Value;
           Elaboro=app.Asignado_3.Value;
%            Firma=app.Asignado_17.Value;
           Hora=app.Asignado_5.Value;
           ServicioInvolucrado=app.Asignado_6.Value;
           FechaIncidente=app.IncidenteFecha.Value;
           Otro=app.QuienReporta_4.Value;
           SeguridadEquipo=app.SeguridadEquipoCheckBox_2.Value;
           DisposivosMedicosEHigiene=app.DispositivosMdicoseHigieneCheckBox.Value;
           MalaOperacion=app.MalaOperacinCheckBox_3.Value;
           ProteccionyVigilancia=app.ProteccinyVigilanciaCheckBox_2.Value;
           MaterialesPeligrosos=app.MaterialesPeligrososCheckBox_2.Value;
           Emergencias=app.EmergenciasCheckBox_2.Value;
           SistemasClave=app.SistemasclaveCheckBox_2.Value;
           ProcesosAdministrativos=app.ProcesosAdministrativosCheckBox_2.Value;
           Medico=app.MdicoCheckBox_2.Value;
           Paciente=app.PacienteCheckBox_2.Value;
           Otros=app.OtrosCheckBox_2.Value;
           Casifalla=app.CasifallaButton.Value;
           EventoBajo=app.EventoAdversoButton.Value;
           EventoAlto=app.Button3.Value;
           FechaFina=app.FechaFin.Value;
           if ~ismissing(FechaFina) || ismissing(FechaIncidente)
               if strcmp(Nombre1,'')~=1
                   Nombre1=strcat(Nombre1,', ')
               end
               if strcmp(Nombre2,'')~=1
                   Nombre2=strcat(Nombre2,', ')
               end
               if strcmp(Nombre3,'')~=1
                   Nombre3=strcat(Nombre3,', ')
               end
               if strcmp(Nombre4,'')~=1
                   Nombre4=strcat(Nombre4,', ')
               end
               if strcmp(Puesto1,'')~=1
                   Puesto1=strcat(Puesto1,', ')
               end
               if strcmp(Puesto2,'')~=1
                   Puesto2=strcat(Puesto2,', ')
               end
               if strcmp(Puesto3,'')~=1
                   Puesto3=strcat(Puesto3,', ')
               end
               if strcmp(Puesto4,'')~=1
                   Puesto4=strcat(Puesto4,', ')
               end
               if strcmp(Antiguedad1,'')~=1
                   Antiguedad1=strcat(Antiguedad1,', ')
               end
               if strcmp(Antiguedad2,'')~=1
                   Antiguedad2=strcat(Antiguedad2,', ')
               end
               if strcmp(Antiguedad3,'')~=1
                   Antiguedad3=strcat(Antiguedad3,', ')
               end
               if strcmp(Antiguedad4,'')~=1
                   Antiguedad4=strcat(Antiguedad4,', ')
               end
               
               Tiempo=strcat(string(FechaFina),', ',Hora);
               Datos=strcat(Nombre1, Puesto1, Antiguedad1, Nombre2, Puesto2, Antiguedad2, Nombre3, Puesto3, Antiguedad3,Nombre4, Puesto4, Antiguedad4);
           caracteristica=' ';
           if SeguridadEquipo==1
           caracteristica=strcat(caracteristica,'Seguridad Equipo, ');
           end
           if DisposivosMedicosEHigiene==1
           caracteristica=strcat(caracteristica,'Dispositivos Médicos e Higiene, ');
           end
           if MalaOperacion==1
           caracteristica=strcat(caracteristica,'Mala Operación, ');
           end
           if ProteccionyVigilancia==1
               caracteristica=strcat(caracteristica,'Protección y Vigilancia, ');
           end
           if MaterialesPeligrosos==1
               caracteristica=strcat(caracteristica,'Materiales Peligrosos, ');
           end
           if Emergencias==1
               caracteristica=strcat(caracteristica,'Emergencias, ');
           end
           if SistemasClave==1
               caracteristica=strcat(caracteristica,'Sistemas clave, ');
           end
           if ProcesosAdministrativos==1
               caracteristica=strcat(caracteristica,'Procesos Administrativos, ');
           end
           if Medico==1
               caracteristica=strcat(caracteristica,'Médico, ');
           end
           if Paciente==1
               caracteristica=strcat(caracteristica,'Paciente, ');
           end
           if Otros==1
               caracteristica=strcat(caracteristica,Otro);
           end
           if Casifalla==1
               incidente='Casi falla: Desviaciòn en el proceso organizacional que se indica antes de que generen un impacto en la estructura personas o en la operaciòn del hospital ';
           elseif EventoBajo==1
               incidente='Evento Adverso: Desviación en el proceso organizacional que tiene bajo impacto en la estructura personas o en la operación del hospital';
           else
               incidente='Evento Adverso: Desviaciòn en el proceso organizacional que tiene alto impacto en la estructura personas o en la operaciòn del hospital';
           end
           app.ImprimirOrdenBoton.Visible=true;
           app.ImprimirOrdenBoton.Enable=true;
           import mlreportgen.dom.Document

           if ~exist('ReportesDeIncidente', 'dir')
                mkdir('ReportesDeIncidente')
           end
           fecha = FechaIncidente;
           equipo = string(app.EquipoSeleccionado);
           nombreArchivo = sprintf('ReportesDeIncidente/Equipo%sIncidente%s.docx',no_contr2,string(FechaIncidente));
           datos = [string(fecha),nombreArchivo,equipo]
           insertIncidente = sprintf('insert into Incidente(fechaIncidente,nombreArchivo,Equipo) values ("%s","%s", "%s");', datos);
           exec(app.conn,insertIncidente);
           app.tablaIncidentes.Data = fetch(app.conn,sprintf('Select  id,nombreArchivo,fechaIncidente from Incidente where Equipo = %s',string(app.EquipoSeleccionado)));
           P=Document(nombreArchivo,'docx','ReporteIncidente.dotx');
           open(P)
           moveToNextHole(P)
           if  strcmp(caracteristica,'') ~= 1
               append(P, caracteristica)
           else
               append(P, " ")
           end
           moveToNextHole(P)
           if  strcmp(incidente,'') ~= 1
               append(P, incidente)
           else
               append(P, " ")
           end
           moveToNextHole(P)
           if  strcmp(ServicioInvolucrado,'') ~= 1
               append(P, ServicioInvolucrado)
           else
               append(P, " ")
           end
           moveToNextHole(P)
          append(P, string(FechaIncidente))
           moveToNextHole(P)
           if  strcmp(Datos,'') ~= 1
               append(P, Datos)
           else
               append(P, " ")
           end
           moveToNextHole(P)
           if  strcmp(Situacion,'') ~= 1
               append(P, Situacion)
           else
               append(P, " ")
           end
           moveToNextHole(P)
           if  strcmp(Background,'') ~= 1
               append(P, Background)
           else
               append(P, " ")
           end
           moveToNextHole(P)
           if  strcmp(AccionesInternas,'') ~= 1
               append(P, AccionesInternas)
           else
               append(P, " ")
           end
           moveToNextHole(P)
           if  strcmp(Recomendaciones,'') ~= 1
               append(P, Recomendaciones)
           else
               append(P, " ")
           end
           moveToNextHole(P)
           if  strcmp(Elaboro,'') ~= 1
               append(P, Elaboro)
           else
               append(P, " ")
           end
           moveToNextHole(P)
           append(P, string(FechaFina))
               aicon=imread('check1.PNG');
                mh=msgbox('Se guardó Incidente','Éxito','custom',aicon);
                th = findall(mh, 'Type', 'Text');                   %get handle to text within msgbox
                th.FontSize = 14;      
                deltaWidth = sum(th.Extent([1,3]))-mh.Position(3) + th.Extent(1);
                deltaHeight = sum(th.Extent([2,4]))-mh.Position(4) + 10;
                mh.Position([3,4]) = mh.Position([3,4]) + [deltaWidth, deltaHeight];           
           close(P)
           
           else
               aicon=imread('warning.jpg');
                mh=msgbox('Información incompleta, falta llenar fecha','Error','custom',aicon);
                th = findall(mh, 'Type', 'Text');                   %get handle to text within msgbox
                th.FontSize = 14;      
                deltaWidth = sum(th.Extent([1,3]))-mh.Position(3) + th.Extent(1);
                deltaHeight = sum(th.Extent([2,4]))-mh.Position(4) + 10;
                mh.Position([3,4]) = mh.Position([3,4]) + [deltaWidth, deltaHeight];
                return
           end
    
        end

        % Cell selection callback: tablaIncidentes
        function seleccionIncidente(app, event)
            indices = event.Indices;
            [indR,indC] = size(indices);
            if indR == 1
                valor = app.tablaIncidentes.Data{indices}
                app.incidenteSeleccionada = string(valor)
                app.EditarReportedeIncidentesButton.Enable = 1
           
            end
        end

        % Button pushed function: EditarReportedeIncidentesButton
        function abrirArchivoIncidente(app, event)
             queryNombreArchivo = sprintf('select nombreArchivo from Incidente where id ="%s"',string(app.incidenteSeleccionada))    
             disp(fetch(app.conn,queryNombreArchivo))
            try 
               winopen(string(fetch(app.conn,queryNombreArchivo)))
            catch ME
                 aicon=imread('warning.jpg');
                mh=msgbox('El archivo no se encontró, fue borrado manualmente por algún usuario','Error','custom',aicon);
                th = findall(mh, 'Type', 'Text');                   %get handle to text within msgbox
                th.FontSize = 14;      
                deltaWidth = sum(th.Extent([1,3]))-mh.Position(3) + th.Extent(1);
                deltaHeight = sum(th.Extent([2,4]))-mh.Position(4) + 10;
                mh.Position([3,4]) = mh.Position([3,4]) + [deltaWidth, deltaHeight];
            end

        end

        % Button pushed function: ImprimirOrdenBoton
        function imprimirOrdernServicio(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Interfaz';
            app.TabGroup = uitabgroup(app.figure1);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @checarSiEquipoSeleccionado, true);
            app.MENPRINCIPALTab = uitab(app.TabGroup);
            app.MENPRINCIPALTab.Title = 'MENÚ PRINCIPAL';
            app.Image2 = uiimage(app.MENPRINCIPALTab);
            app.Image2.ImageSource = 'Logo.png';
            app.BUSCARButton = uibutton(app.MENPRINCIPALTab, 'push');
            app.BUSCARButton.ButtonPushedFcn = createCallbackFcn(app, @BUSCARButtonPushed, true);
            app.BUSCARButton.Text = 'BUSCAR';
            app.ALTADEEQUIPOButton = uibutton(app.MENPRINCIPALTab, 'push');
            app.ALTADEEQUIPOButton.ButtonPushedFcn = createCallbackFcn(app, @ALTADEEQUIPOButtonPushed, true);
            app.ALTADEEQUIPOButton.Text = 'ALTA DE EQUIPO';
            app.ORDENDESERVICIOButton = uibutton(app.MENPRINCIPALTab, 'push');
            app.ORDENDESERVICIOButton.ButtonPushedFcn = createCallbackFcn(app, @ORDENDESERVICIOButtonPushed, true);
            app.ORDENDESERVICIOButton.Text = 'ORDEN DE SERVICIO';
            app.DETALLESDESERVICIOButton = uibutton(app.MENPRINCIPALTab, 'push');
            app.DETALLESDESERVICIOButton.ButtonPushedFcn = createCallbackFcn(app, @DETALLESDESERVICIOButtonPushed, true);
            app.DETALLESDESERVICIOButton.Text = 'DETALLES DE SERVICIO';
            app.REPORTEDEINCIDENTESButton = uibutton(app.MENPRINCIPALTab, 'push');
            app.REPORTEDEINCIDENTESButton.ButtonPushedFcn = createCallbackFcn(app, @REPORTEDEINCIDENTESButtonPushed, true);
            app.REPORTEDEINCIDENTESButton.Text = 'REPORTE DE INCIDENTES';
            app.CALENDARIZACIONButton = uibutton(app.MENPRINCIPALTab, 'push');
            app.CALENDARIZACIONButton.ButtonPushedFcn = createCallbackFcn(app, @CALENDARIZACIONButtonPushed, true);
            app.CALENDARIZACIONButton.Text = 'CALENDARIZACIÓN';
            app.CALENDARIZARMANTENIMIENTOButton = uibutton(app.MENPRINCIPALTab, 'push');
            app.CALENDARIZARMANTENIMIENTOButton.ButtonPushedFcn = createCallbackFcn(app, @CALENDARIZARMANTENIMIENTOButtonPushed, true);
            app.CALENDARIZARMANTENIMIENTOButton.Text = 'CALENDARIZAR MANTENIMIENTO';
            app.REGISTRODEINGENIEROSButton = uibutton(app.MENPRINCIPALTab, 'push');
            app.REGISTRODEINGENIEROSButton.ButtonPushedFcn = createCallbackFcn(app, @REGISTRODEINGENIEROSButtonPushed, true);
            app.REGISTRODEINGENIEROSButton.Text = 'REGISTRO DE INGENIEROS';
            app.Image4 = uiimage(app.MENPRINCIPALTab);
            app.Image4.ImageSource = 'p9.png';
            app.Image4_2 = uiimage(app.MENPRINCIPALTab);
            app.Image4_2.ImageSource = 'Reporte.jpg.png';
            app.Image4_3 = uiimage(app.MENPRINCIPALTab);
            app.Image4_3.ImageSource = 'historial.png';
            app.Image4_4 = uiimage(app.MENPRINCIPALTab);
            app.Image4_4.ImageSource = 'p1.png';
            app.Image4_5 = uiimage(app.MENPRINCIPALTab);
            app.Image4_5.ImageSource = 'calendario.png';
            app.Image4_6 = uiimage(app.MENPRINCIPALTab);
            app.Image4_6.ImageSource = 'generacion de calendario.png';
            app.Image4_7 = uiimage(app.MENPRINCIPALTab);
            app.Image4_7.ImageSource = 'AltaBaja.png';
            app.Image4_8 = uiimage(app.MENPRINCIPALTab);
            app.Image4_8.ImageSource = 'LUPA1.jpg.png';
            app.BUSCARTab = uitab(app.TabGroup);
            app.BUSCARTab.Title = 'BUSCAR';
            app.Buscarpor = uilabel(app.BUSCARTab);
            app.Buscarpor.Text = 'Buscar por:';
            app.Buscarpormenu = uidropdown(app.BUSCARTab);
            app.Buscarpormenu.Items = {'Equipo', 'Número de control', 'Marca', 'Modelo', 'No. De serie', 'Área', 'Proveedor de compra', 'Accesorios', 'No. De activo fijo', 'Ubicación', 'Fecha de Instalación', 'Estado de equipo', 'Proveedor de servicio', 'Refacciones cambiadas', 'Consumibles'};
            app.Buscarpormenu.ValueChangedFcn = createCallbackFcn(app, @actualizarTipoFiltro, true);
            app.Buscarpormenu.Value = 'Equipo';
            app.Buscarporedit = uieditfield(app.BUSCARTab, 'text');
            app.Buscarporedit.ValueChangedFcn = createCallbackFcn(app, @filtrar, true);
            app.PanelTabla = uipanel(app.BUSCARTab);
            app.Inventario = uitable(app.PanelTabla);
            app.Inventario.ColumnName = {'Número de Control'; 'Equipo'; 'Marca'; 'Modelo'; 'Número de Serie'; 'Num Activo Fijo'; 'Área'; 'Ubicación'; 'Proveedor de Compra'; 'Fecha Instalación'; 'Estado'; 'Consumibles'; 'Accesorios'; 'Refacciones Cambiadas'; 'Proveedor de Servicio'; 'Contacto'; 'Teléfono'};
            app.Inventario.RowName = {''};
            app.Inventario.ColumnSortable = true;
            app.Inventario.CellSelectionCallback = createCallbackFcn(app, @selectedCell, true);
            app.Buscarpormenu_2 = uidropdown(app.BUSCARTab);
            app.Buscarpormenu_2.Items = {'Número de control', 'Equipo', 'Marca', 'Modelo', 'No. De serie', 'Área', 'Proveedor de compra', 'Accesorios', 'No. De activo fijo', 'Ubicación', 'Fecha de Instalación', 'Estado de equipo', 'Provedor de servicio', 'Refacciones cambiadas', 'Consumibles'};
            app.Buscarpormenu_2.Visible = 'off';
            app.Buscarpormenu_2.Value = 'Número de control';
            app.Buscarporedit_2 = uieditfield(app.BUSCARTab, 'text');
            app.Buscarporedit_2.ValueChangedFcn = createCallbackFcn(app, @filtrar2, true);
            app.Buscarporedit_2.Visible = 'off';
            app.OpcionesFiltro = uipanel(app.BUSCARTab);
            app.Label_12 = uilabel(app.OpcionesFiltro);
            app.Label_12.Text = '';
            app.AreaCheckBox = uicheckbox(app.OpcionesFiltro);
            app.AreaCheckBox.ValueChangedFcn = createCallbackFcn(app, @EsconderArea, true);
            app.AreaCheckBox.Text = 'Area';
            app.AreaCheckBox.Value = true;
            app.ProveedordeCompraCheckBox = uicheckbox(app.OpcionesFiltro);
            app.ProveedordeCompraCheckBox.ValueChangedFcn = createCallbackFcn(app, @EsconderProveedorCompra, true);
            app.ProveedordeCompraCheckBox.Text = 'Proveedor de Compra';
            app.ProveedordeCompraCheckBox.Value = true;
            app.AccesoriosCheckBox = uicheckbox(app.OpcionesFiltro);
            app.AccesoriosCheckBox.ValueChangedFcn = createCallbackFcn(app, @EsconderAccesorios, true);
            app.AccesoriosCheckBox.Text = 'Accesorios';
            app.AccesoriosCheckBox.Value = true;
            app.NumActivoFijoCheckBox = uicheckbox(app.OpcionesFiltro);
            app.NumActivoFijoCheckBox.ValueChangedFcn = createCallbackFcn(app, @EsconderNumActivo, true);
            app.NumActivoFijoCheckBox.Text = 'Num. Activo Fijo';
            app.NumActivoFijoCheckBox.Value = true;
            app.UbicacionCheckBox = uicheckbox(app.OpcionesFiltro);
            app.UbicacionCheckBox.ValueChangedFcn = createCallbackFcn(app, @EsconderUbicacion, true);
            app.UbicacionCheckBox.Text = 'Ubicación';
            app.UbicacionCheckBox.Value = true;
            app.FechadeInstalacinCheckBox = uicheckbox(app.OpcionesFiltro);
            app.FechadeInstalacinCheckBox.ValueChangedFcn = createCallbackFcn(app, @EsconderFecha, true);
            app.FechadeInstalacinCheckBox.Text = 'Fecha de Instalación';
            app.FechadeInstalacinCheckBox.Value = true;
            app.EstadodelEquipoCheckBox = uicheckbox(app.OpcionesFiltro);
            app.EstadodelEquipoCheckBox.ValueChangedFcn = createCallbackFcn(app, @EsconderEstado, true);
            app.EstadodelEquipoCheckBox.Text = 'Estado del Equipo';
            app.EstadodelEquipoCheckBox.Value = true;
            app.ProveedorDeServicioCheckBox = uicheckbox(app.OpcionesFiltro);
            app.ProveedorDeServicioCheckBox.ValueChangedFcn = createCallbackFcn(app, @EsconderProveedor, true);
            app.ProveedorDeServicioCheckBox.Text = 'Proveedor De Servicio';
            app.ProveedorDeServicioCheckBox.Value = true;
            app.ContactoCheckBox = uicheckbox(app.OpcionesFiltro);
            app.ContactoCheckBox.ValueChangedFcn = createCallbackFcn(app, @EsconderContacto, true);
            app.ContactoCheckBox.Text = 'Contacto';
            app.ContactoCheckBox.Value = true;
            app.TelefonoCheckBox = uicheckbox(app.OpcionesFiltro);
            app.TelefonoCheckBox.ValueChangedFcn = createCallbackFcn(app, @EsconderTelefono, true);
            app.TelefonoCheckBox.Text = 'Consumibles';
            app.TelefonoCheckBox.Value = true;
            app.RefaccionesCambiadasCheckBox = uicheckbox(app.OpcionesFiltro);
            app.RefaccionesCambiadasCheckBox.ValueChangedFcn = createCallbackFcn(app, @EsconderRefacciones, true);
            app.RefaccionesCambiadasCheckBox.Text = 'Refacciones Cambiadas';
            app.RefaccionesCambiadasCheckBox.Value = true;
            app.Button_3 = uibutton(app.BUSCARTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @exportarAExcel, true);
            app.Button_3.Icon = 'excel.png';
            app.Button_3.Text = '';
            app.DETALLEDELEQUIPOTab = uitab(app.TabGroup);
            app.DETALLEDELEQUIPOTab.Title = 'DETALLE DEL EQUIPO';
            app.Panel_6 = uipanel(app.DETALLEDELEQUIPOTab);
            app.InformacindelequipoPanel_2 = uipanel(app.Panel_6);
            app.InformacindelequipoPanel_2.Title = 'Información del equipo';
            app.ActualizarInformacindelEquipoButton = uibutton(app.InformacindelequipoPanel_2, 'push');
            app.ActualizarInformacindelEquipoButton.ButtonPushedFcn = createCallbackFcn(app, @actualizarEquipo, true);
            app.ActualizarInformacindelEquipoButton.Text = 'Actualizar Información del Equipo';
            app.InformacindelequipoPanel_3 = uipanel(app.InformacindelequipoPanel_2);
            app.InformacindelequipoPanel_3.Title = 'Información del equipo';
            app.text9_33 = uilabel(app.InformacindelequipoPanel_3);
            app.text9_33.Text = 'EQUIPO*';
            app.text9_34 = uilabel(app.InformacindelequipoPanel_3);
            app.text9_34.Text = 'MARCA*';
            app.text9_35 = uilabel(app.InformacindelequipoPanel_3);
            app.text9_35.Text = 'MODELO*';
            app.text9_36 = uilabel(app.InformacindelequipoPanel_3);
            app.text9_36.Text = 'No. DE SERIE*';
            app.text9_37 = uilabel(app.InformacindelequipoPanel_3);
            app.text9_37.Text = 'PROVEEDOR DE COMPRA';
            app.text9_38 = uilabel(app.InformacindelequipoPanel_3);
            app.text9_38.Text = 'ACCESORIOS';
            app.text9_39 = uilabel(app.InformacindelequipoPanel_3);
            app.text9_39.Text = 'NÚMERO DE ACTIVO FIJO';
            app.text9_40 = uilabel(app.InformacindelequipoPanel_3);
            app.text9_40.Text = 'FECHA DE INSTALACIÓN';
            app.text9_41 = uilabel(app.InformacindelequipoPanel_3);
            app.text9_41.Text = 'ESTADO DEL EQUIPO';
            app.FechaSolicitud_24 = uieditfield(app.InformacindelequipoPanel_3, 'text');
            app.FechaSolicitud_25 = uieditfield(app.InformacindelequipoPanel_3, 'text');
            app.FechaSolicitud_26 = uieditfield(app.InformacindelequipoPanel_3, 'text');
            app.FechaSolicitud_27 = uieditfield(app.InformacindelequipoPanel_3, 'text');
            app.FechaSolicitud_28 = uieditfield(app.InformacindelequipoPanel_3, 'text');
            app.FechaSolicitud_29 = uieditfield(app.InformacindelequipoPanel_3, 'text');
            app.FechaSolicitud_30 = uieditfield(app.InformacindelequipoPanel_3, 'text');
            app.FechaSolicitud_31 = uieditfield(app.InformacindelequipoPanel_3, 'text');
            app.FechaSolicitud_32 = uieditfield(app.InformacindelequipoPanel_3, 'text');
            app.UBICACINDropDownLabel_2 = uilabel(app.InformacindelequipoPanel_3);
            app.UBICACINDropDownLabel_2.Text = 'UBICACIÓN';
            app.UBICACIONDropDown_2 = uidropdown(app.InformacindelequipoPanel_3);
            app.UBICACIONDropDown_2.Items = {};
            app.UBICACIONDropDown_2.Value = {};
            app.AREADropDown_2Label = uilabel(app.InformacindelequipoPanel_3);
            app.AREADropDown_2Label.Text = 'AREA';
            app.AREADropDown_2 = uidropdown(app.InformacindelequipoPanel_3);
            app.AREADropDown_2.Items = {};
            app.AREADropDown_2.Value = {};
            app.PROVEEDORDESERVICIODropDown_2Label = uilabel(app.InformacindelequipoPanel_3);
            app.PROVEEDORDESERVICIODropDown_2Label.Text = 'PROVEEDOR DE SERVICIO';
            app.PROVEEDORDESERVICIODropDown_2 = uidropdown(app.InformacindelequipoPanel_3);
            app.PROVEEDORDESERVICIODropDown_2.Items = {'Carefusion', 'GMMC MEXICANA', 'MIE'};
            app.PROVEEDORDESERVICIODropDown_2.Value = 'Carefusion';
            app.REFACCIONESCAMBIADASEditField_2Label = uilabel(app.InformacindelequipoPanel_3);
            app.REFACCIONESCAMBIADASEditField_2Label.Text = 'REFACCIONES CAMBIADAS';
            app.REFACCIONESCAMBIADASEditField_2 = uieditfield(app.InformacindelequipoPanel_3, 'text');
            app.text9_42 = uilabel(app.InformacindelequipoPanel_3);
            app.text9_42.Text = ' CONSUMIBLES';
            app.Consumibles_2 = uieditfield(app.InformacindelequipoPanel_3, 'text');
            app.BorrarEquipoButton = uibutton(app.InformacindelequipoPanel_2, 'push');
            app.BorrarEquipoButton.ButtonPushedFcn = createCallbackFcn(app, @borrarEquipo, true);
            app.BorrarEquipoButton.Text = 'Borrar Equipo';
            app.TabGroup2 = uitabgroup(app.Panel_6);
            app.OrdendeservicioTab = uitab(app.TabGroup2);
            app.OrdendeservicioTab.Title = 'Orden de servicio';
            app.tablaOrdenesServicio = uitable(app.OrdendeservicioTab);
            app.tablaOrdenesServicio.ColumnName = {'Folio'; 'Archivo'; 'Fecha de creación'};
            app.tablaOrdenesServicio.RowName = {};
            app.tablaOrdenesServicio.CellSelectionCallback = createCallbackFcn(app, @ordenSeleccionada, true);
            app.EditarOrdendeServicioButton = uibutton(app.OrdendeservicioTab, 'push');
            app.EditarOrdendeServicioButton.ButtonPushedFcn = createCallbackFcn(app, @editarOrdenServicio, true);
            app.EditarOrdendeServicioButton.Text = 'Editar Orden de Servicio';
            app.ReportedeincidentesTab = uitab(app.TabGroup2);
            app.ReportedeincidentesTab.Title = 'Reporte de incidentes';
            app.tablaIncidentes = uitable(app.ReportedeincidentesTab);
            app.tablaIncidentes.ColumnName = {'Identificador'; 'Archivo'; 'Fecha de creación'};
            app.tablaIncidentes.RowName = {};
            app.tablaIncidentes.CellSelectionCallback = createCallbackFcn(app, @seleccionIncidente, true);
            app.EditarReportedeIncidentesButton = uibutton(app.ReportedeincidentesTab, 'push');
            app.EditarReportedeIncidentesButton.ButtonPushedFcn = createCallbackFcn(app, @abrirArchivoIncidente, true);
            app.EditarReportedeIncidentesButton.Text = 'Editar Reporte de Incidentes';
            app.MantenimientosTab = uitab(app.TabGroup2);
            app.MantenimientosTab.Title = 'Mantenimientos';
            app.tablaMantenimiento = uitable(app.MantenimientosTab);
            app.tablaMantenimiento.ColumnName = {'Folio'; 'Tipo de mantenimiento'; 'Estado'; 'Fecha del Mantenimiento'};
            app.tablaMantenimiento.RowName = {};
            app.tablaMantenimiento.CellSelectionCallback = createCallbackFcn(app, @seleccionEditarMantenimiento, true);
            app.EditarMantenimientoButton = uibutton(app.MantenimientosTab, 'push');
            app.EditarMantenimientoButton.ButtonPushedFcn = createCallbackFcn(app, @EditarMantenimiento, true);
            app.EditarMantenimientoButton.Text = 'Editar Mantenimiento';
            app.ActualizarinformacindelmantenimientoPanel = uipanel(app.MantenimientosTab);
            app.ActualizarinformacindelmantenimientoPanel.Title = 'Actualizar información del mantenimiento';
            app.ActualizarinformacindelmantenimientoPanel.Visible = 'off';
            app.FechaDatePickerLabel_2 = uilabel(app.ActualizarinformacindelmantenimientoPanel);
            app.FechaDatePickerLabel_2.Text = 'Fecha';
            app.ActualizarMantenimientoFecha = uidatepicker(app.ActualizarinformacindelmantenimientoPanel);
            app.ActualizarMantenimientoFecha.DisplayFormat = 'dd/MMM/uuuu';
            app.ActualizarEstadoServicio = uibuttongroup(app.ActualizarinformacindelmantenimientoPanel);
            app.ActualizarEstadoServicio.Title = 'Estado del Servicio';
            app.ProgramadoButton_2 = uiradiobutton(app.ActualizarEstadoServicio);
            app.ProgramadoButton_2.Text = 'Programado';
            app.ProgramadoButton_2.Value = true;
            app.RealizadoButton_2 = uiradiobutton(app.ActualizarEstadoServicio);
            app.RealizadoButton_2.Text = 'Realizado';
            app.ActualizarTipoServicio = uibuttongroup(app.ActualizarinformacindelmantenimientoPanel);
            app.ActualizarTipoServicio.Title = 'Tipo de Servicio';
            app.MantenimientoCorrectivoButton_2 = uiradiobutton(app.ActualizarTipoServicio);
            app.MantenimientoCorrectivoButton_2.Text = 'Mantenimiento Correctivo';
            app.MantenimientoCorrectivoButton_2.Value = true;
            app.MantenimientoPreventivoButton_2 = uiradiobutton(app.ActualizarTipoServicio);
            app.MantenimientoPreventivoButton_2.Text = 'Mantenimiento Preventivo';
            app.ACTUALIZARButton = uibutton(app.ActualizarinformacindelmantenimientoPanel, 'push');
            app.ACTUALIZARButton.ButtonPushedFcn = createCallbackFcn(app, @ActualizarServicio, true);
            app.ACTUALIZARButton.Text = 'ACTUALIZAR';
            app.ALTADEEQUIPOTab = uitab(app.TabGroup);
            app.ALTADEEQUIPOTab.Title = 'ALTA DE EQUIPO';
            app.AltadeequipoPanel = uipanel(app.ALTADEEQUIPOTab);
            app.AltadeequipoPanel.Title = 'Alta de equipo';
            app.InformacindelequipoPanel_4 = uipanel(app.AltadeequipoPanel);
            app.InformacindelequipoPanel_4.Title = 'Información del equipo';
            app.text9_43 = uilabel(app.InformacindelequipoPanel_4);
            app.text9_43.Text = 'EQUIPO*';
            app.text9_44 = uilabel(app.InformacindelequipoPanel_4);
            app.text9_44.Text = 'MARCA*';
            app.text9_45 = uilabel(app.InformacindelequipoPanel_4);
            app.text9_45.Text = 'MODELO*';
            app.text9_46 = uilabel(app.InformacindelequipoPanel_4);
            app.text9_46.Text = 'No. DE SERIE*';
            app.text9_47 = uilabel(app.InformacindelequipoPanel_4);
            app.text9_47.Text = 'PROVEEDOR DE COMPRA';
            app.text9_48 = uilabel(app.InformacindelequipoPanel_4);
            app.text9_48.Text = 'ACCESORIOS';
            app.text9_49 = uilabel(app.InformacindelequipoPanel_4);
            app.text9_49.Text = 'NÚMERO DE ACTIVO FIJO';
            app.text9_50 = uilabel(app.InformacindelequipoPanel_4);
            app.text9_50.Text = 'FECHA DE INSTALACIÓN';
            app.text9_51 = uilabel(app.InformacindelequipoPanel_4);
            app.text9_51.Text = 'ESTADO DEL EQUIPO';
            app.FechaSolicitud_15 = uieditfield(app.InformacindelequipoPanel_4, 'text');
            app.FechaSolicitud_16 = uieditfield(app.InformacindelequipoPanel_4, 'text');
            app.FechaSolicitud_17 = uieditfield(app.InformacindelequipoPanel_4, 'text');
            app.FechaSolicitud_18 = uieditfield(app.InformacindelequipoPanel_4, 'text');
            app.FechaSolicitud_19 = uieditfield(app.InformacindelequipoPanel_4, 'text');
            app.FechaSolicitud_20 = uieditfield(app.InformacindelequipoPanel_4, 'text');
            app.FechaSolicitud_21 = uieditfield(app.InformacindelequipoPanel_4, 'text');
            app.FechaSolicitud_22 = uieditfield(app.InformacindelequipoPanel_4, 'text');
            app.FechaSolicitud_23 = uieditfield(app.InformacindelequipoPanel_4, 'text');
            app.UBICACINDropDownLabel_3 = uilabel(app.InformacindelequipoPanel_4);
            app.UBICACINDropDownLabel_3.Text = 'UBICACIÓN';
            app.UBICACIONDropDown = uidropdown(app.InformacindelequipoPanel_4);
            app.UBICACIONDropDown.Items = {};
            app.UBICACIONDropDown.Value = {};
            app.AREADropDown_3Label = uilabel(app.InformacindelequipoPanel_4);
            app.AREADropDown_3Label.Text = 'AREA';
            app.AREADropDown = uidropdown(app.InformacindelequipoPanel_4);
            app.AREADropDown.Items = {};
            app.AREADropDown.Value = {};
            app.PROVEEDORDESERVICIODropDown_3Label = uilabel(app.InformacindelequipoPanel_4);
            app.PROVEEDORDESERVICIODropDown_3Label.Text = 'PROVEEDOR DE SERVICIO';
            app.PROVEEDORDESERVICIODropDown = uidropdown(app.InformacindelequipoPanel_4);
            app.PROVEEDORDESERVICIODropDown.Items = {'Carefusion', 'GMMC MEXICANA', 'MIE'};
            app.PROVEEDORDESERVICIODropDown.Value = 'Carefusion';
            app.Button_4 = uibutton(app.InformacindelequipoPanel_4, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @MostrarProveedorServ, true);
            app.Button_4.Text = '+';
            app.REFACCIONESCAMBIADASEditField_3Label = uilabel(app.InformacindelequipoPanel_4);
            app.REFACCIONESCAMBIADASEditField_3Label.Text = 'REFACCIONES CAMBIADAS';
            app.REFACCIONESCAMBIADASEditField = uieditfield(app.InformacindelequipoPanel_4, 'text');
            app.text9_52 = uilabel(app.InformacindelequipoPanel_4);
            app.text9_52.Text = ' CONSUMIBLES';
            app.Consumibles = uieditfield(app.InformacindelequipoPanel_4, 'text');
            app.GUARDAREQUIPOButton = uibutton(app.AltadeequipoPanel, 'push');
            app.GUARDAREQUIPOButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarEquipo, true);
            app.GUARDAREQUIPOButton.Text = 'GUARDAR EQUIPO';
            app.DatosdelproveedordeservicioPanel = uipanel(app.ALTADEEQUIPOTab);
            app.DatosdelproveedordeservicioPanel.Title = 'Nuevo proveedor de Servicio';
            app.DatosdelproveedordeservicioPanel.Visible = 'off';
            app.CONTACTOEditFieldLabel = uilabel(app.DatosdelproveedordeservicioPanel);
            app.CONTACTOEditFieldLabel.Text = 'CONTACTO';
            app.CONTACTOEditField = uieditfield(app.DatosdelproveedordeservicioPanel, 'text');
            app.ProvNombre = uieditfield(app.DatosdelproveedordeservicioPanel, 'text');
            app.TELFONOEditFieldLabel = uilabel(app.DatosdelproveedordeservicioPanel);
            app.TELFONOEditFieldLabel.Text = 'TELÉFONO';
            app.TELFONOEditField = uieditfield(app.DatosdelproveedordeservicioPanel, 'numeric');
            app.TELFONOEditField.Value = 55;
            app.NOMBRELabel = uilabel(app.DatosdelproveedordeservicioPanel);
            app.NOMBRELabel.Text = 'NOMBRE';
            app.GUARDARPROVEEDORDESERVICIOButton = uibutton(app.DatosdelproveedordeservicioPanel, 'push');
            app.GUARDARPROVEEDORDESERVICIOButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarProveedorServicio, true);
            app.GUARDARPROVEEDORDESERVICIOButton.Text = 'GUARDAR PROVEEDOR DE SERVICIO';
            app.ORDENDESERVICIOTab = uitab(app.TabGroup);
            app.ORDENDESERVICIOTab.Title = 'ORDEN DE SERVICIO';
            app.Panel = uipanel(app.ORDENDESERVICIOTab);
            app.PanelOrden = uipanel(app.Panel);
            app.uipanel1 = uipanel(app.PanelOrden);
            app.text5 = uilabel(app.uipanel1);
            app.text5.Text = 'No. DE CONTROL';
            app.text6 = uilabel(app.uipanel1);
            app.text6.Text = 'No. DE SERIE';
            app.text7 = uilabel(app.uipanel1);
            app.text7.Text = 'EQUIPO';
            app.ordenNumControl = uilabel(app.uipanel1);
            app.ordenNumControl.Text = '';
            app.ordenNumSerie = uilabel(app.uipanel1);
            app.ordenNumSerie.Text = '';
            app.OrdenEquipoNombre = uilabel(app.uipanel1);
            app.OrdenEquipoNombre.Text = '';
            app.uipanel3 = uipanel(app.PanelOrden);
            app.text9 = uilabel(app.uipanel3);
            app.text9.Text = 'SOLICITUD DE SERVICIO FOLIO';
            app.OrdenFolio = uilabel(app.uipanel3);
            app.OrdenFolio.Text = '';
            app.text21 = uilabel(app.uipanel3);
            app.text21.Text = 'FECHA DE SOLICITUD*';
            app.text22 = uilabel(app.uipanel3);
            app.text22.Text = 'FECHA DE TERMINACIÓN';
            app.FechaSolicitud = uidatepicker(app.uipanel3);
            app.FechaSolicitud.DisplayFormat = 'dd/MMM/uuuu';
            app.FechaTerminacion = uidatepicker(app.uipanel3);
            app.FechaTerminacion.DisplayFormat = 'dd/MMM/uuuu';
            app.uipanel2 = uipanel(app.PanelOrden);
            app.text10 = uilabel(app.uipanel2);
            app.text10.Text = 'DEPARTAMENTO SOLICITANTE';
            app.Depto = uieditfield(app.uipanel2, 'text');
            app.Falla = uieditfield(app.uipanel2, 'text');
            app.QuienReporta = uieditfield(app.uipanel2, 'text');
            app.text15 = uilabel(app.uipanel2);
            app.text15.Text = 'DEPARTAMENTO';
            app.text19 = uilabel(app.uipanel2);
            app.text19.Text = 'FALLA REPORTADA';
            app.text23 = uilabel(app.uipanel2);
            app.text23.Text = 'REPORTA';
            app.uipanel4 = uipanel(app.PanelOrden);
            app.TrabajoHecho = uieditfield(app.uipanel4, 'text');
            app.text12 = uilabel(app.uipanel4);
            app.text12.Text = 'INGENIERIA BIOMEDICA';
            app.text24 = uilabel(app.uipanel4);
            app.text24.Text = 'RECIBE:';
            app.QuienRecibe = uieditfield(app.uipanel4, 'text');
            app.text25 = uilabel(app.uipanel4);
            app.text25.Text = 'TRABAJO REALIZADO';
            app.ASIGNADOADropDownLabel = uilabel(app.uipanel4);
            app.ASIGNADOADropDownLabel.Text = 'ASIGNADO A:';
            app.ASIGNADOADropDown = uidropdown(app.uipanel4);
            app.ASIGNADOADropDown.Items = {};
            app.ASIGNADOADropDown.Value = {};
            app.uipanel7 = uipanel(app.PanelOrden);
            app.Refacciones = uieditfield(app.uipanel7, 'text');
            app.tect30 = uilabel(app.uipanel7);
            app.tect30.Text = 'PARTES NUEVAS';
            app.uipanel10 = uipanel(app.PanelOrden);
            app.Observaciones = uieditfield(app.uipanel10, 'text');
            app.text31 = uilabel(app.uipanel10);
            app.text31.Text = 'OBSERVACIONES:';
            app.DatosGrales = uilabel(app.PanelOrden);
            app.DatosGrales.Text = 'DATOS GENERALES';
            app.uipanel2_2 = uipanel(app.PanelOrden);
            app.text10_2 = uilabel(app.uipanel2_2);
            app.text10_2.Text = 'COSTOS ESTIMADOS';
            app.text15_3 = uilabel(app.uipanel2_2);
            app.text15_3.Text = 'COSTO REFACCIONES';
            app.text15_4 = uilabel(app.uipanel2_2);
            app.text15_4.Text = 'COSTO SERVICIO EXTERNO';
            app.TotalHoras = uieditfield(app.uipanel2_2, 'text');
            app.CostoRefacciones = uieditfield(app.uipanel2_2, 'text');
            app.CostoServExterno = uieditfield(app.uipanel2_2, 'text');
            app.text15_5 = uilabel(app.uipanel2_2);
            app.text15_5.Text = 'TOTAL';
            app.Total = uieditfield(app.uipanel2_2, 'text');
            app.HORASINGENIEROEditFieldLabel = uilabel(app.uipanel2_2);
            app.HORASINGENIEROEditFieldLabel.Text = 'HORAS/INGENIERO';
            app.HORASINGENIEROEditField = uieditfield(app.uipanel2_2, 'numeric');
            app.uipanel2_3 = uipanel(app.PanelOrden);
            app.text10_3 = uilabel(app.uipanel2_3);
            app.text10_3.Text = 'DEPARTAMENTO SOLICITANTE';
            app.RecibidoPor = uieditfield(app.uipanel2_3, 'text');
            app.Comentarios = uieditfield(app.uipanel2_3, 'text');
            app.text15_6 = uilabel(app.uipanel2_3);
            app.text15_6.Text = 'RECIBIDO DE CONFORMIDAD POR:';
            app.text19_2 = uilabel(app.uipanel2_3);
            app.text19_2.Text = 'COMENTARIOS:';
            app.text23_2 = uilabel(app.uipanel2_3);
            app.text23_2.Text = 'FECHA:';
            app.fechaOrdenServicio = uidatepicker(app.uipanel2_3);
            app.IB = uilabel(app.PanelOrden);
            app.IB.Text = 'INGENIERÍA BIOMEDICA';
            app.Image = uiimage(app.PanelOrden);
            app.Image.ImageSource = 'Logo.png';
            app.FALLAENCONTRADAButtonGroup = uibuttongroup(app.PanelOrden);
            app.FALLAENCONTRADAButtonGroup.Title = 'FALLA ENCONTRADA';
            app.EquipoDefectuosoButton = uiradiobutton(app.FALLAENCONTRADAButtonGroup);
            app.EquipoDefectuosoButton.Text = 'Equipo Defectuoso';
            app.EquipoDefectuosoButton.Value = true;
            app.MalaOperacionButton = uiradiobutton(app.FALLAENCONTRADAButtonGroup);
            app.MalaOperacionButton.Text = 'Mala Operación';
            app.AsesoraTecnicaButton = uiradiobutton(app.FALLAENCONTRADAButtonGroup);
            app.AsesoraTecnicaButton.Text = 'Asesoría Técnica';
            app.TRABAJOREALIZADOButtonGroup = uibuttongroup(app.PanelOrden);
            app.TRABAJOREALIZADOButtonGroup.Title = 'TRABAJO REALIZADO';
            app.ManttoPreventivoButton = uiradiobutton(app.TRABAJOREALIZADOButtonGroup);
            app.ManttoPreventivoButton.Text = 'Mantto. Preventivo';
            app.ManttoPreventivoButton.Value = true;
            app.ManttoCorrectivoButton = uiradiobutton(app.TRABAJOREALIZADOButtonGroup);
            app.ManttoCorrectivoButton.Text = 'Mantto. Correctivo';
            app.AsesoraenServicioButton = uiradiobutton(app.TRABAJOREALIZADOButtonGroup);
            app.AsesoraenServicioButton.Text = 'Asesoría en Servicio';
            app.ServicioExternoButton = uiradiobutton(app.TRABAJOREALIZADOButtonGroup);
            app.ServicioExternoButton.Text = 'Servicio Externo';
            app.GuardarOrdenBoton = uibutton(app.Panel, 'push');
            app.GuardarOrdenBoton.ButtonPushedFcn = createCallbackFcn(app, @GuardarOrdenServicio, true);
            app.GuardarOrdenBoton.Text = 'Guardar';
            app.ImprimirOrdenBoton = uibutton(app.Panel, 'push');
            app.ImprimirOrdenBoton.ButtonPushedFcn = createCallbackFcn(app, @imprimirOrdernServicio, true);
            app.ImprimirOrdenBoton.Enable = 'off';
            app.ImprimirOrdenBoton.Visible = 'off';
            app.ImprimirOrdenBoton.Text = 'Imprimir';
            app.REPORTEDEINCIDENTESTab = uitab(app.TabGroup);
            app.REPORTEDEINCIDENTESTab.Title = 'REPORTE DE INCIDENTES';
            app.PanelOrden_3 = uipanel(app.REPORTEDEINCIDENTESTab);
            app.uipanel1_3 = uipanel(app.PanelOrden_3);
            app.DatosGrales_3 = uilabel(app.uipanel1_3);
            app.DatosGrales_3.Text = 'CLASIFICACIÓN DE INCIDENTES';
            app.uipanel4_3 = uipanel(app.PanelOrden_3);
            app.text12_6 = uilabel(app.uipanel4_3);
            app.text12_6.Text = 'PERSONAL INVOLUCRADO';
            app.text26_3 = uilabel(app.uipanel4_3);
            app.text26_3.Text = 'Servicio Involucrado';
            app.Asignado_6 = uieditfield(app.uipanel4_3, 'text');
            app.text12_7 = uilabel(app.uipanel4_3);
            app.text12_7.Text = 'NOMBRE';
            app.text12_8 = uilabel(app.uipanel4_3);
            app.text12_8.Text = 'PUESTO';
            app.text12_9 = uilabel(app.uipanel4_3);
            app.text12_9.Text = 'ANTIGÜEDAD';
            app.text15_18 = uilabel(app.uipanel4_3);
            app.text15_18.Text = '1.';
            app.text15_19 = uilabel(app.uipanel4_3);
            app.text15_19.Text = '2.';
            app.text15_20 = uilabel(app.uipanel4_3);
            app.text15_20.Text = '3.';
            app.text15_21 = uilabel(app.uipanel4_3);
            app.text15_21.Text = '4.';
            app.Asignado_8 = uieditfield(app.uipanel4_3, 'text');
            app.Asignado_9 = uieditfield(app.uipanel4_3, 'text');
            app.Asignado_10 = uieditfield(app.uipanel4_3, 'text');
            app.Asignado_11 = uieditfield(app.uipanel4_3, 'text');
            app.Asignado_12 = uieditfield(app.uipanel4_3, 'text');
            app.Asignado_13 = uieditfield(app.uipanel4_3, 'text');
            app.Asignado_14 = uieditfield(app.uipanel4_3, 'text');
            app.Asignado_15 = uieditfield(app.uipanel4_3, 'text');
            app.Asignado_16 = uieditfield(app.uipanel4_3, 'text');
            app.Asignado_17 = uieditfield(app.uipanel4_3, 'text');
            app.Asignado_18 = uieditfield(app.uipanel4_3, 'text');
            app.Asignado_19 = uieditfield(app.uipanel4_3, 'text');
            app.FechaIncidenteLabel = uilabel(app.uipanel4_3);
            app.FechaIncidenteLabel.Text = 'Fecha Incidente *';
            app.IncidenteFecha = uidatepicker(app.uipanel4_3);
            app.uipanel5_5 = uipanel(app.PanelOrden_3);
            app.SeguridadEquipoCheckBox_2 = uicheckbox(app.uipanel5_5);
            app.SeguridadEquipoCheckBox_2.Text = 'Seguridad Equipo';
            app.MalaOperacinCheckBox_3 = uicheckbox(app.uipanel5_5);
            app.MalaOperacinCheckBox_3.Text = 'Mala Operación';
            app.DispositivosMdicoseHigieneCheckBox = uicheckbox(app.uipanel5_5);
            app.DispositivosMdicoseHigieneCheckBox.Text = 'Dispositivos Médicos e Higiene';
            app.uipanel2_7 = uipanel(app.PanelOrden_3);
            app.text10_7 = uilabel(app.uipanel2_7);
            app.text10_7.Text = 'SITUACIÓN';
            app.CostoServExterno_3 = uieditfield(app.uipanel2_7, 'text');
            app.IB_3 = uilabel(app.PanelOrden_3);
            app.IB_3.Text = 'REGISTRO DE INCIDENTES';
            app.Image_3 = uiimage(app.PanelOrden_3);
            app.Image_3.ImageSource = 'Logo.png';
            app.uipanel5_6 = uipanel(app.PanelOrden_3);
            app.ProteccinyVigilanciaCheckBox_2 = uicheckbox(app.uipanel5_6);
            app.ProteccinyVigilanciaCheckBox_2.Text = 'Protección y Vigilancia';
            app.EmergenciasCheckBox_2 = uicheckbox(app.uipanel5_6);
            app.EmergenciasCheckBox_2.Text = 'Emergencias';
            app.MaterialesPeligrososCheckBox_2 = uicheckbox(app.uipanel5_6);
            app.MaterialesPeligrososCheckBox_2.Text = 'Materiales Peligrosos';
            app.SistemasclaveCheckBox_2 = uicheckbox(app.uipanel5_6);
            app.SistemasclaveCheckBox_2.Text = 'Sistemas clave';
            app.uipanel5_7 = uipanel(app.PanelOrden_3);
            app.ProcesosAdministrativosCheckBox_2 = uicheckbox(app.uipanel5_7);
            app.ProcesosAdministrativosCheckBox_2.Text = 'Procesos Administrativos';
            app.PacienteCheckBox_2 = uicheckbox(app.uipanel5_7);
            app.PacienteCheckBox_2.Text = 'Paciente';
            app.MdicoCheckBox_2 = uicheckbox(app.uipanel5_7);
            app.MdicoCheckBox_2.Text = 'Médico';
            app.OtrosCheckBox_2 = uicheckbox(app.uipanel5_7);
            app.OtrosCheckBox_2.Text = 'Otros';
            app.QuienReporta_4 = uieditfield(app.uipanel5_7, 'text');
            app.uipanel2_8 = uipanel(app.PanelOrden_3);
            app.text10_8 = uilabel(app.uipanel2_8);
            app.text10_8.Text = 'BACKGROUND';
            app.CostoServExterno_4 = uieditfield(app.uipanel2_8, 'text');
            app.text12_3 = uilabel(app.PanelOrden_3);
            app.text12_3.Text = 'ELABORO:';
            app.Asignado_3 = uieditfield(app.PanelOrden_3, 'text');
            app.text12_4 = uilabel(app.PanelOrden_3);
            app.text12_4.Text = 'FIRMA:';
            app.text12_5 = uilabel(app.PanelOrden_3);
            app.text12_5.Text = 'HORA';
            app.Asignado_5 = uieditfield(app.PanelOrden_3, 'text');
            app.uipanel2_9 = uipanel(app.PanelOrden_3);
            app.text10_9 = uilabel(app.uipanel2_9);
            app.text10_9.Text = 'ACCIONES';
            app.CostoServExterno_5 = uieditfield(app.uipanel2_9, 'text');
            app.text10_10 = uilabel(app.uipanel2_9);
            app.text10_10.Text = 'INMEDIATAS';
            app.uipanel2_10 = uipanel(app.PanelOrden_3);
            app.text10_11 = uilabel(app.uipanel2_10);
            app.text10_11.Text = 'RECOMENDACIONES';
            app.CostoServExterno_6 = uieditfield(app.uipanel2_10, 'text');
            app.Label = uilabel(app.PanelOrden_3);
            app.Label.Text = '__________________';
            app.TIPODEINCIDENTEButtonGroup = uibuttongroup(app.PanelOrden_3);
            app.TIPODEINCIDENTEButtonGroup.Title = 'TIPO DE INCIDENTE';
            app.CasifallaButton = uiradiobutton(app.TIPODEINCIDENTEButtonGroup);
            app.CasifallaButton.Text = {'Casi falla: Desviaciòn en el proceso organizacional que se indica antes de que generen un impacto'; 'en la estructura personas o en la operaciòn del hospital'};
            app.CasifallaButton.Value = true;
            app.EventoAdversoButton = uiradiobutton(app.TIPODEINCIDENTEButtonGroup);
            app.EventoAdversoButton.Text = {'Evento Adverso: Desviaciòn en el proceso organizacional que tiene bajo impacto en la estructura'; ' personas o en la operaciòn del hospital'};
            app.Button3 = uiradiobutton(app.TIPODEINCIDENTEButtonGroup);
            app.Button3.Text = {'Evento Adverso: Desviaciòn en el proceso organizacional que tiene alto impacto en la estructura'; ' personas o en la operaciòn del hospital'};
            app.text5_2 = uilabel(app.PanelOrden_3);
            app.text5_2.Text = 'No. DE CONTROL';
            app.text6_2 = uilabel(app.PanelOrden_3);
            app.text6_2.Text = 'No. DE SERIE';
            app.text7_2 = uilabel(app.PanelOrden_3);
            app.text7_2.Text = 'EQUIPO';
            app.ordenNumControl_2 = uilabel(app.PanelOrden_3);
            app.ordenNumControl_2.Text = '';
            app.ordenNumSerie_2 = uilabel(app.PanelOrden_3);
            app.ordenNumSerie_2.Text = '';
            app.OrdenEquipoNombre_2 = uilabel(app.PanelOrden_3);
            app.OrdenEquipoNombre_2.Text = '';
            app.FechadelReporrteLabel = uilabel(app.PanelOrden_3);
            app.FechadelReporrteLabel.Text = 'Fecha  del Reporrte *';
            app.FechaFin = uidatepicker(app.PanelOrden_3);
            app.FechaFin.DisplayFormat = 'dd/MMM/uuuu';
            app.GuardarIncidenteButton = uibutton(app.REPORTEDEINCIDENTESTab, 'push');
            app.GuardarIncidenteButton.ButtonPushedFcn = createCallbackFcn(app, @guardarIncidente, true);
            app.GuardarIncidenteButton.Text = 'Guardar Incidente';
            app.CALENDARIZARMANTENIMIENTOTab = uitab(app.TabGroup);
            app.CALENDARIZARMANTENIMIENTOTab.Title = 'CALENDARIZAR MANTENIMIENTO';
            app.Panel_4 = uipanel(app.CALENDARIZARMANTENIMIENTOTab);
            app.ESPECIFICACIONESPanel = uipanel(app.Panel_4);
            app.ESPECIFICACIONESPanel.Title = 'ESPECIFICACIONES ';
            app.text9_14 = uilabel(app.ESPECIFICACIONESPanel);
            app.text9_14.Text = 'EQUIPO';
            app.text9_15 = uilabel(app.ESPECIFICACIONESPanel);
            app.text9_15.Text = 'MARCA';
            app.text9_16 = uilabel(app.ESPECIFICACIONESPanel);
            app.text9_16.Text = 'MODELO';
            app.text9_17 = uilabel(app.ESPECIFICACIONESPanel);
            app.text9_17.Text = 'No. DE SERIE';
            app.text9_18 = uilabel(app.ESPECIFICACIONESPanel);
            app.text9_18.Text = 'AREA';
            app.text9_19 = uilabel(app.ESPECIFICACIONESPanel);
            app.text9_19.Text = 'PROVEEDOR DE COMPRA';
            app.text9_20 = uilabel(app.ESPECIFICACIONESPanel);
            app.text9_20.Text = 'NÚMERO DE ACTIVO FIJO';
            app.text9_21 = uilabel(app.ESPECIFICACIONESPanel);
            app.text9_21.Text = 'UBICACIÓN';
            app.text9_22 = uilabel(app.ESPECIFICACIONESPanel);
            app.text9_22.Text = 'PROVEEDOR DE SERVICIOS';
            app.servicioEquipoNombre = uilabel(app.ESPECIFICACIONESPanel);
            app.servicioEquipoNombre.Text = '';
            app.servicioMarca = uilabel(app.ESPECIFICACIONESPanel);
            app.servicioMarca.Text = '';
            app.servicioModelo = uilabel(app.ESPECIFICACIONESPanel);
            app.servicioModelo.Text = '';
            app.servicioNumSerie = uilabel(app.ESPECIFICACIONESPanel);
            app.servicioNumSerie.Text = '';
            app.servicioArea = uilabel(app.ESPECIFICACIONESPanel);
            app.servicioArea.Text = '';
            app.servicioProveedorCompra = uilabel(app.ESPECIFICACIONESPanel);
            app.servicioProveedorCompra.Text = '';
            app.servicioNumActivoFlujo = uilabel(app.ESPECIFICACIONESPanel);
            app.servicioNumActivoFlujo.Text = '';
            app.servicioUbicacion = uilabel(app.ESPECIFICACIONESPanel);
            app.servicioUbicacion.Text = '';
            app.servicioProveedorServicio = uilabel(app.ESPECIFICACIONESPanel);
            app.servicioProveedorServicio.Text = '';
            app.TipodeServicioButtonGroup = uibuttongroup(app.Panel_4);
            app.TipodeServicioButtonGroup.Title = 'Tipo de Servicio';
            app.MantenimientoCorrectivoButton = uiradiobutton(app.TipodeServicioButtonGroup);
            app.MantenimientoCorrectivoButton.Text = 'Mantenimiento Correctivo';
            app.MantenimientoCorrectivoButton.Value = true;
            app.MantenimientoPreventivoButton = uiradiobutton(app.TipodeServicioButtonGroup);
            app.MantenimientoPreventivoButton.Text = 'Mantenimiento Preventivo';
            app.FechaDatePickerLabel = uilabel(app.Panel_4);
            app.FechaDatePickerLabel.Text = 'Fecha';
            app.MantenimientoFecha = uidatepicker(app.Panel_4);
            app.MantenimientoFecha.DisplayFormat = 'dd/MMM/uuuu';
            app.AGENDARServicioButton = uibutton(app.Panel_4, 'push');
            app.AGENDARServicioButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarServicio, true);
            app.AGENDARServicioButton.Text = 'AGENDAR Servicio';
            app.Panel_5 = uipanel(app.Panel_4);
            app.IB_4 = uilabel(app.Panel_5);
            app.IB_4.Text = 'CALENDARIZACIÓN DE SERVICIO';
            app.Image_4 = uiimage(app.Panel_5);
            app.Image_4.ImageSource = 'generacion de calendario.png';
            app.EstadodelServicioButtonGroup = uibuttongroup(app.Panel_4);
            app.EstadodelServicioButtonGroup.Title = 'Estado del Servicio';
            app.ProgramadoButton = uiradiobutton(app.EstadodelServicioButtonGroup);
            app.ProgramadoButton.Text = 'Programado';
            app.ProgramadoButton.Value = true;
            app.RealizadoButton = uiradiobutton(app.EstadodelServicioButtonGroup);
            app.RealizadoButton.Text = 'Realizado';
            app.CALENDARIOTab = uitab(app.TabGroup);
            app.CALENDARIOTab.Title = 'CALENDARIO';
            app.TablaCalendario = uitable(app.CALENDARIOTab);
            app.TablaCalendario.ColumnName = {'Equipo'; 'Modelo'; 'tipo de mantenimiento'; 'estado'; 'fecha del mantenimiento'};
            app.TablaCalendario.RowName = {};
            app.Buscarpor_2 = uilabel(app.CALENDARIOTab);
            app.Buscarpor_2.Text = 'Mes:';
            app.CalendarioMes = uidropdown(app.CALENDARIOTab);
            app.CalendarioMes.Items = {'Jan', 'Feb', 'Mar', 'Abr', 'May', 'Jun', 'Jul', 'Aug', 'Sep', 'Oct', 'Nov', 'Dec'};
            app.CalendarioMes.Value = 'Oct';
            app.Buscarpor_3 = uilabel(app.CALENDARIOTab);
            app.Buscarpor_3.Text = 'año:';
            app.CalendarioYear = uidropdown(app.CALENDARIOTab);
            app.CalendarioYear.Items = {'2019', '2018', '2017', '2016', '2015', '2014'};
            app.CalendarioYear.Value = '2019';
            app.FiltrarButton = uibutton(app.CALENDARIOTab, 'push');
            app.FiltrarButton.ButtonPushedFcn = createCallbackFcn(app, @filtrarCalendario, true);
            app.FiltrarButton.Text = 'Filtrar';
            app.RemoverFiltroButton = uibutton(app.CALENDARIOTab, 'push');
            app.RemoverFiltroButton.ButtonPushedFcn = createCallbackFcn(app, @removerCalendarioFiltro, true);
            app.RemoverFiltroButton.Text = 'Remover Filtro';
            app.INGENIEROSTab = uitab(app.TabGroup);
            app.INGENIEROSTab.Title = 'INGENIEROS';
            app.imagenIngenirero = uiimage(app.INGENIEROSTab);
            app.imagenIngenirero.ImageSource = 'AltaBaja.png';
            app.ALTAEINFORMACINDEINGENIEROSLabel = uilabel(app.INGENIEROSTab);
            app.ALTAEINFORMACINDEINGENIEROSLabel.Text = 'ALTA E INFORMACIÓN DE INGENIEROS';
            app.RegistrodeIngenieroNuevoPanel = uipanel(app.INGENIEROSTab);
            app.RegistrodeIngenieroNuevoPanel.Title = 'Registro de Ingeniero Nuevo';
            app.NombreCompletoEditFieldLabel = uilabel(app.RegistrodeIngenieroNuevoPanel);
            app.NombreCompletoEditFieldLabel.Text = 'Nombre Completo:';
            app.IngenieroNombre = uieditfield(app.RegistrodeIngenieroNuevoPanel, 'text');
            app.ExperienciaLabel = uilabel(app.RegistrodeIngenieroNuevoPanel);
            app.ExperienciaLabel.Text = 'Experiencia:';
            app.IngenieroExp = uitextarea(app.RegistrodeIngenieroNuevoPanel);
            app.CapacitacionesLabel = uilabel(app.RegistrodeIngenieroNuevoPanel);
            app.CapacitacionesLabel.Text = 'Capacitaciones:';
            app.IngenieroCapacitaciones = uitextarea(app.RegistrodeIngenieroNuevoPanel);
            app.GUARDARINGENIEROButton = uibutton(app.RegistrodeIngenieroNuevoPanel, 'push');
            app.GUARDARINGENIEROButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarIngeniero, true);
            app.GUARDARINGENIEROButton.Text = 'GUARDAR INGENIERO';
            app.IngenieroTabla = uitable(app.INGENIEROSTab);
            app.IngenieroTabla.ColumnName = {'Nombre'; 'Experiencia'; 'Capacitaciones'};
            app.IngenieroTabla.RowName = {};
            app.BuscarEditFieldLabel = uilabel(app.INGENIEROSTab);
            app.BuscarEditFieldLabel.Text = 'Buscar';
            app.IngenieroBuscar = uieditfield(app.INGENIEROSTab, 'text');
            app.IngenieroBuscar.ValueChangedFcn = createCallbackFcn(app, @buscarIngeniero, true);
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterfazCompleta

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Interfaz_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end